classdef main_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ChannelTogglerTab               matlab.ui.container.Tab
        AOMSwitchesPanel                matlab.ui.container.Panel
        repumpSwitch                    matlab.ui.control.StateButton
        ControlSwitch                   matlab.ui.control.StateButton
        ProbeSwitch                     matlab.ui.control.StateButton
        coolingSwitch                   matlab.ui.control.StateButton
        ZEEMANSwitch                    matlab.ui.control.StateButton
        AntiHHcurrentPanel              matlab.ui.container.Panel
        IGBT_circ                       matlab.ui.control.StateButton
        IGBT_rect                       matlab.ui.control.StateButton
        circCurrentSliderLabel          matlab.ui.control.Label
        circCurrentSlider               matlab.ui.control.Slider
        rectCurrentSliderLabel          matlab.ui.control.Label
        rectCurrentSlider               matlab.ui.control.Slider
        circCurrentVal                  matlab.ui.control.Label
        circCurrentValLabel             matlab.ui.control.Label
        NotenabledLabel                 matlab.ui.control.Label
        ICEPanel                        matlab.ui.container.Panel
        ICEADDR0                        matlab.ui.control.StateButton
        ICEADDR2                        matlab.ui.control.StateButton
        ICEEVTTRIG                      matlab.ui.control.StateButton
        ICEADDR1                        matlab.ui.control.StateButton
        MWsourcePanel                   matlab.ui.container.Panel
        MW_CTL                          matlab.ui.control.StateButton
        MWSourceSwitch                  matlab.ui.control.StateButton
        MW_HLD                          matlab.ui.control.StateButton
        ProbeDDSPanel                   matlab.ui.container.Panel
        DDS2_HLD                        matlab.ui.control.StateButton
        DDS2_CTL                        matlab.ui.control.StateButton
        ScopeTrigger                    matlab.ui.control.StateButton
        BIASPSU_TRIG                    matlab.ui.control.StateButton
        CoolingDDSPanel                 matlab.ui.container.Panel
        DDS1_CTL                        matlab.ui.control.StateButton
        DDS1_HLD                        matlab.ui.control.StateButton
        CameratriggerPanel              matlab.ui.container.Panel
        pixelflyPlaneTrig               matlab.ui.control.StateButton
        pixelflyTopTrig                 matlab.ui.control.StateButton
        UnassignedchannelsPanel         matlab.ui.container.Panel
        DigOut12_2                      matlab.ui.control.StateButton
        DigOut13_2                      matlab.ui.control.StateButton
        DigOut14_2                      matlab.ui.control.StateButton
        DigOut5_2                       matlab.ui.control.StateButton
        DigOut15_2                      matlab.ui.control.StateButton
        DigOut6_2                       matlab.ui.control.StateButton
        DigOut16_2                      matlab.ui.control.StateButton
        DigOut7_2                       matlab.ui.control.StateButton
        DigOut19_2                      matlab.ui.control.StateButton
        DigOut8_2                       matlab.ui.control.StateButton
        DigOut18_2                      matlab.ui.control.StateButton
        DigOut9_2                       matlab.ui.control.StateButton
        DigOut10_2                      matlab.ui.control.StateButton
        DigOut20_2                      matlab.ui.control.StateButton
        DigOut11_2                      matlab.ui.control.StateButton
        DigOut21_2                      matlab.ui.control.StateButton
        DigOut17_2                      matlab.ui.control.StateButton
        DigOut22_2                      matlab.ui.control.StateButton
        DigOut23_2                      matlab.ui.control.StateButton
        DigOut24_2                      matlab.ui.control.StateButton
        DigOut25_2                      matlab.ui.control.StateButton
        DispenserSwitch                 matlab.ui.control.StateButton
        DipoleTrapPanel                 matlab.ui.container.Panel
        BlueDTSwitch                    matlab.ui.control.StateButton
        PurpleDTSwitch                  matlab.ui.control.StateButton
        Tab3                            matlab.ui.container.Tab
        AddactiontosequenceButton       matlab.ui.control.Button
        SavesequenceButton              matlab.ui.control.Button
        actionTable                     matlab.ui.control.Table
        actionDropDown_2Label           matlab.ui.control.Label
        actionDropDown                  matlab.ui.control.DropDown
        seqTable                        matlab.ui.control.Table
        moveupButton                    matlab.ui.control.Button
        movedownButton                  matlab.ui.control.Button
        removeButton                    matlab.ui.control.Button
        LoadsequenceButton              matlab.ui.control.Button
        RunsequenceButton               matlab.ui.control.Button
        ExperimentManagerTab            matlab.ui.container.Tab
        pTable                          matlab.ui.control.Table
        paramstableLabel                matlab.ui.control.Label
        loadexperimentButton            matlab.ui.control.Button
        saveexperimentButton            matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        sequenceLabel                   matlab.ui.control.Label
        loadseqButton                   matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        ParametersweepPanel             matlab.ui.container.Panel
        outerloopparamDropDownLabel     matlab.ui.control.Label
        outerloopparamDropDown          matlab.ui.control.DropDown
        innerloopparamDropDownLabel     matlab.ui.control.Label
        innerloopparamDropDown          matlab.ui.control.DropDown
        outerstartvalEditFieldLabel     matlab.ui.control.Label
        outerstartvalEditField          matlab.ui.control.NumericEditField
        innerstartvalEditFieldLabel     matlab.ui.control.Label
        innerstartvalEditField          matlab.ui.control.NumericEditField
        outerendvalEditFieldLabel       matlab.ui.control.Label
        outerendvalEditField            matlab.ui.control.NumericEditField
        outerendvalEditField_2Label     matlab.ui.control.Label
        outerendvalEditField_2          matlab.ui.control.NumericEditField
        outernumstepsEditFieldLabel     matlab.ui.control.Label
        outernumstepsEditField          matlab.ui.control.NumericEditField
        innernumstepsEditFieldLabel     matlab.ui.control.Label
        innernumstepsEditField          matlab.ui.control.NumericEditField
        PlottingTab                     matlab.ui.container.Tab
        ExperimentdateEditField_2Label  matlab.ui.control.Label
        ExperimentDate                  matlab.ui.control.EditField
        ExpTable                        matlab.ui.control.Table
        PlotingAreaPanel                matlab.ui.container.Panel
        validdateLampLabel              matlab.ui.control.Label
        validdateLamp                   matlab.ui.control.Lamp
        validfileLampLabel              matlab.ui.control.Label
        validfileLamp                   matlab.ui.control.Lamp
        loadToWorkspaceButton           matlab.ui.control.Button
        OpenasfigureButton              matlab.ui.control.Button
        DDSTab                          matlab.ui.container.Tab
        ShovachCOMDropDownLabel         matlab.ui.control.Label
        ShovachCOMDropDown              matlab.ui.control.DropDown
        TabGroup2                       matlab.ui.container.TabGroup
        RampOptionsTab                  matlab.ui.container.Tab
        StartFreqEditFieldLabel         matlab.ui.control.Label
        StartFreqEditField              matlab.ui.control.NumericEditField
        EndFreqEditFieldLabel           matlab.ui.control.Label
        EndFreqEditField                matlab.ui.control.NumericEditField
        dfEditFieldLabel                matlab.ui.control.Label
        dfEditField                     matlab.ui.control.NumericEditField
        EndFreqEditField_3Label         matlab.ui.control.Label
        EndFreqEditField_3              matlab.ui.control.NumericEditField
        ToggleRampButton                matlab.ui.control.StateButton
        SetFreqTab                      matlab.ui.container.Tab
        InitShovachButton               matlab.ui.control.Button
        ExtClockCheckBox                matlab.ui.control.CheckBox
        LasercontrolTab                 matlab.ui.container.Tab
        ICELasersPanel                  matlab.ui.container.Panel
        coolingemissionButton           matlab.ui.control.StateButton
        repumpemissionButton            matlab.ui.control.StateButton
        ICEconnectionButton             matlab.ui.control.StateButton
        repumplockButton                matlab.ui.control.StateButton
        coolinglockButton               matlab.ui.control.StateButton
        repumptemplockLampLabel         matlab.ui.control.Label
        repumptemplockLamp              matlab.ui.control.Lamp
        coolingtemplockLamp_2Label      matlab.ui.control.Label
        coolingtemplockLamp             matlab.ui.control.Lamp
        repumpemissionLampLabel         matlab.ui.control.Label
        repumpemissionLamp              matlab.ui.control.Lamp
        coolingemissionLamp_2Label      matlab.ui.control.Label
        coolingemissionLamp             matlab.ui.control.Lamp
        repumplockLampLabel             matlab.ui.control.Label
        repumplockLamp                  matlab.ui.control.Lamp
        coolinglockLampLabel            matlab.ui.control.Label
        coolinglockLamp                 matlab.ui.control.Lamp
        TAPanel                         matlab.ui.container.Panel
        TAONLampLabel                   matlab.ui.control.Label
        TAONLamp                        matlab.ui.control.Lamp
        cameraTab                       matlab.ui.container.Tab
        pixelflyAxes                    matlab.ui.control.UIAxes
        thorcam1Axes                    matlab.ui.control.UIAxes
        LabelNumericEditField           matlab.ui.control.Label
        pixeflyExposureEdit             matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        thorcam1ExposureEdit            matlab.ui.control.NumericEditField
        pixelflyPreviewToggle           matlab.ui.control.StateButton
        thorcam1PreviewToggle           matlab.ui.control.StateButton
        ImageInfoTextAreaLabel          matlab.ui.control.Label
        ImageInfoTextArea               matlab.ui.control.TextArea
        dipoletraplooperTab             matlab.ui.container.Tab
        startButton                     matlab.ui.control.StateButton
        topDipoleView                   matlab.ui.control.UIAxes
        sideDipoleView                  matlab.ui.control.UIAxes
        LoadMOTButton                   matlab.ui.control.Button
        UnloadMOTButton                 matlab.ui.control.Button
        DEBUGSwitchLabel                matlab.ui.control.Label
        DEBUGSwitch                     matlab.ui.control.ToggleSwitch
        labshutdownButton               matlab.ui.control.Button
        InitButton                      matlab.ui.control.Button
        StatusLabel                     matlab.ui.control.Label
        LogBox                          matlab.ui.control.ListBox
    end

    properties (Access = public)
        channelTable %configuration of hardware channels
        DEBUG=1 %app DEBUG mode, 1/0 for developing at home 
        pixelfly_exposure;
        pixelfly_vid; %pixelfly configuration object
        pixelfly_src;%pixelfly configuration object
        circCoilConn;% connection object to circular AHH coils
        thorcam1; %thorlabs1camera
        thorcam1ImageData; %thorcam1 image
    end

    properties (Access = private)
        myTimer %timer for periodically calculating the image fitting properties
        xVec; %x coord vec for images
        yVec;% y coord vec for images
        testGaussian; %for testing images 
        s =sqncr()% a default sequencer object
        
        seqTableCurrentRow=1; %indicating where in the sequence table we are workign
        
        expRunning = 0 % this is a flag to indicate if en experiment is running. If expRunning == 1 then all channle toggles are diactivated.
        dateHeader % Description
        expPath % Description
        ExpetimentDatePkr% experiment date picker 
        ExpetimentDateLabl%experiment date picker label 
        dipoleLooperTimer % timer for dipole optimization
    end


    methods (Access = private)
    
        function circCoilCurrentCheck(app)
            app.circularCoilCurrentIndicator.Value='hello';
        end
        
        function cameraImageFitting(app)
            
            if app.thorcam1PreviewToggle.Value && app.DEBUG
                p=fitImageGaussian2D(app.xVec,app.yVec,app.testGaussian,1);
            elseif app.thorcam1PreviewToggle.Value && ~app.DEBUG
                im = app.thorcam1ImageData;
                
                if ~isempty(im)
                    [xsize,ysize]=size(app.thorcam1ImageData);
                    xvec=linspace(0,1,xsize);
                    yvec=linspace(0,1,ysize);
                    p=fitImageGaussian2D(xvec,yvec,app.thorcam1ImageData,1);
                else
                    disp('no image!');
                    p=zeros(1,7);
                end
                
            end
            if ~app.thorcam1PreviewToggle.Value
                p=zeros(1,7);
            end
                app.ImageInfoTextArea.Value={'Thorcam data:',...
                                            ['Integrated intensity: ' num2str(p(7))],...
                                            ['Maximum intensity: ' num2str(p(2))],...
                                            ['Center of Gaussian: ' num2str(p(3)) ',' num2str(p(4))],...
                                            ['Width of Gaussian in x: ' num2str(p(5))],...
                                            ['Width of Gaussian in y: ' num2str(p(6))]};
            
        end
        
        function [x,y,Z]=makeTestGaussian(app)
            %function to generate a 2d gaussian for testing purposes
            %x,y are vectors of coordinates, Z is a 2D image
            
            x=linspace(0,1,100);y=linspace(0,1,100);
            [X,Y]=ndgrid(x,y);
            sigma=0.1;
            Z=exp(-(X-0.5).^2/2/sigma^2)*exp(-(Y-.5).^2/2/sigma^2);
            
        end

        function results = toggleChannel(app,caller)
            value = caller.Value;
            if value==1
                value='high';
            else
                value='low';
            end
            if app.expRunning == 0
                app.s.clear();
                app.s.addBlock({'setDigitalChannel','channel',caller.Text,'value',value,'duration',0});
                app.s.runStep();
                results = 1;
            elseif strcmpi(value,'high')
                caller.Value = 'low';
                results = 0;
            elseif strcmpi(value,'low')
                caller.Value = 'high';
                results = 0;
            end
        end
        
        function myUpdateFcn(app)
%         currString= get(app.StatusTextArea,'String')
%         currString{end+1}='Appended!';
%          jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
%             jCmdWin = jDesktop.getClient('Command Window');
%             jTextArea = jCmdWin.getComponent(0).getViewport.getView;
%         set(app.StatusTextArea,'String',char(jTextArea.getText));   
        app.StatusTextArea.String='here';
        end
        
        function results = setAO(app,chanName,value)
            if app.expRunning == 0
                app.s.clear();
                app.s.addBlock({'setAnalogChannel','channel',chanName,'value',value,'duration',0});
                app.s.run();
                results = 1;
            else
                results = 0;
            end
        end
        
        function value=getSeq(app)
            value=app.s.toTable;
        end
        
        
        function results = addActionToSeq(app)
            thisRow={app.actionDropDown.Value};
            colNames=app.actionTable.ColumnName;
            colVals=app.actionTable.Data;
            if ~iscell(colVals)
                colVals={colVals};
            end
            for ind=1:length(app.actionTable.ColumnName)
                thisRow{end+1}=colNames{ind};
                thisRow{end+1}=colVals{ind};
            end
            app.s.addBlock(thisRow);
            app.updateSeqTable;
        end
        
        function results = updateSeqTable(app)
            global p
            t=app.s.toTable;
            app.seqTable.Data=table2cell(t) ;
            app.seqTable.ColumnName=t.Properties.VariableNames;
            nameCol=find(strcmpi('name',app.seqTable.ColumnName));
            
            if ~(nameCol==1)
                temp=app.seqTable.Data(:,1);
                tempName=app.seqTable.ColumnName{1};
                app.seqTable.Data(:,1)=app.seqTable.Data(:,nameCol);
                app.seqTable.Data(:,nameCol)=temp;
                app.seqTable.ColumnName{1}='name';
                app.seqTable.ColumnName{nameCol}=tempName;
                if strcmpi(tempName,'channel')
                    app.seqTable.ColumnFormat{find(strcmpi(app.seqTable.ColumnName,'channel'))}=p.ct.Row';
%                     app.seqTable;
                end
                
            end
            
            app.seqTable.ColumnEditable=true;
            
        end
        
        function results = verifyConnection(app)
            global p
            global inst
            global r
            initp
            initinst
            initr
        end
        
        function Explist = getExpList(app,year,month,day)
            %this function gets a date (year, month, day, as strings) and returns a list of experiment indices, names, and times.  
            basePath=fullfile(fileparts(which('basicImports')),'..','..','..','Measurements');
            fullpath = fullfile(basePath,year,month,day);
            app.expPath = fullpath;
            try
            logFile = readtable(fullfile(fullpath,'log.txt'));
            jf=java.text.DecimalFormat; %this allows for comma to seperate thousends, i.e 10000->10,000.
            Explist = cell(length(logFile.Var1),4);
            for ii=1:length(logFile.Var1)
               fileDir = dir(fullfile(fullpath,[logFile.Var1{ii}, '.mat']));
               fileSize = round(fileDir.bytes/1000); %size of file in kB.
               fileSizeText = char(jf.format(fileSize));
               indInd = strfind(logFile.Var1{ii},'_')+1;
               Explist{ii,1} =  logFile.Var1{ii}(indInd:end);
               Explist{ii,2} = logFile.Var2{ii};
               Explist{ii,3} = datestr(logFile.Var3(ii),13);
               Explist{ii,4} = fileSizeText;
            end
            catch err
                if strcmpi(err.identifier,'MATLAB:readtable:OpenFailed')
%                     app.StatusTextArea.Value='cannot open log file for this date';
                    gui_logger(app,sprintf('log file error on %s-%s-%s',day,month,year));
                    Explist=nan;
                end
            end
            
        end
        
        function results = datechange (app,src,event)
            value = char(event.Value);
            day = value(1:2);
            if str2num(day)<1 || str2num(day)>31
                error('day = %d, and it must be 1-31. Check input date!',day)
            end
            month = value(4:5);
            if str2num(month)<1 || str2num(month)>12
               error('month = %d, and it must be 1-12.  Check input date!',month)
            end
            year = value(7:10);
            if str2num(year)<2017 || str2num(year)>2023
                 error('year = %d, and it must be 2017-2023.  Check input date!',year)
            end
            app.dateHeader = sprintf('%s%s%s',day,month,year((end-1):end));
            expList = getExpList(app,year,month,day);
%             disp(expList)
            if ~iscell(expList)
                app.ExpTable.Data={};
                app.validdateLamp.Color='r';
                gui_logger(app,sprintf('cannot read experiment file on %s-%s-%s',day,month,year));
            else
                app.validdateLamp.Color='g';
                app.ExpTable.Data = expList;
                gui_logger(app,sprintf('opened date %s-%s-%s',day,month,year));
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%         jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
%             jCmdWin = jDesktop.getClient('Command Window');
%             jTextArea = jCmdWin.getComponent(0).getViewport.getView;
%             cwText = char(jTextArea.getText);
%             try
%             set(jTextArea,'CaretUpdateCallback',disp('here'))

            global p
            global inst
            global r

            initp
            p.DEBUG=1;
            app.actionDropDown.Items=p.actionsMap.keys;
            initr
            initinst
            
            app.ExpetimentDatePkr = uidatepicker(app.PlottingTab,'DisplayFormat' ,'dd/MM/yyyy','ValueChangedFcn' , @app.datechange,'Position' , [100 428 116 22]);% experinet date uidatepicker
            app.ExpetimentDateLabl = uilabel(app.PlottingTab,'HorizontalAlignment','center','Position' , [8 428 94 22],'Text','Experiment date');
            app.ExperimentDate.Visible = false;
            app.ExperimentdateEditField_2Label.Visible = false;
%             jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
%             jCmdWin = jDesktop.getClient('Command Window');
%             jTextArea = jCmdWin.getComponent(0).getViewport.getView;
%             cwText = char(jTextArea.getText);
            
%             diary on
%             app.diaryFile=get(0,'DiaryFile');
%             app.logTimer=timer('StartDelay',1,'Period',1,'ExecutionMode','fixedSpacing');
%             start(app.myTimer);
%             app.myTimer=timer('StartDelay',1,'Period',1,'ExecutionMode','fixedSpacing');
%             app.myTimer.TimerFcn=@(x,y)app.cameraImageFitting;
%             [x,y,Z]=makeTestGaussian(app);
%             app.testGaussian=Z;
%             app.xVec=x;
%             app.yVec=y;
%             start(app.myTimer);
%             if ~app.DEBUG
%             [vid,src]=pixelflySetup(100); %configure pixelfly
%             app.pixelfly_vid=vid;
%             app.pixelfly_src=src;
%             
%             psuSetup; %run AHH PSU setup script
%             app.circCoilConn=tcpip('10.10.10.102',8462); %open connection to the circular PSU
%             fopen(app.circCoilConn);            
%         end
      
        end

        % Value changed function: DEBUGSwitch
        function DEBUGSwitchValueChanged(app, event)
            global p
            value = app.DEBUGSwitch.Value;
            if strcmp(value,'On')
                p.DEBUG=1;
                app.DEBUG = 1;
            else 
                p.DEBUG=0;
                app.DEBUG = 0;
            end
            disp(['DEBUG MODE: ' num2str(p.DEBUG)]);
        end

        % Button pushed function: LoadMOTButton
        function LoadMOTButtonPushed(app, event)
            app.verifyConnection
            app.s.clear;
            app.s.addBlock({'Load MOT'});
            app.s.run();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.DEBUG == 0
            clearInst;
            end
            delete(app);
            
        end

        % Button pushed function: UnloadMOTButton
        function UnloadMOTButtonPushed(app, event)
            app.verifyConnection
            app.s.clear;
            app.s.addBlock({'Release MOT'});
            app.s.run();
        end

        % Value changed function: coolingSwitch
        function coolingSwitchToggle(app, event)
            app.toggleChannel(app.coolingSwitch);
        end

        % Value changed function: pixeflyExposureEdit
        function pixeflyExposureEditValueChanged(app, event)
            value = app.pixeflyExposureEdit.Value;
            exposure=value;
        end

        % Value changed function: pixelflyPreviewToggle
        function pixelflyPreviewToggleValueChanged(app, event)
            state = app.pixelflyPreviewToggle.Value;
            if app.DEBUG~=1
                if isempty(app.pixelfly_vid)
                    [vid,src]=pixelflySetup(100); %configure pixelfly
                    app.pixelfly_vid=vid;
                    app.pixelfly_src=src;
                end
            vidRes = app.pixelfly_vid.VideoResolution; 
            nBands = app.pixelfly_vid.NumberOfBands; 
            im = image(app.pixelflyAxes,zeros(vidRes(2), vidRes(1), nBands)); 
            end 
            
            if app.DEBUG==1 && state ==1
                im = image(app.pixelflyAxes,100*rand(1024,1024));
            elseif app.DEBUG==1 && state ==0
                im = image(app.pixelflyAxes,zeros(1024,1024));
            end
            
            if state == 1 && app.DEBUG~=1
        	preview(app.pixelfly_vid,im);
            elseif state == 0 && app.DEBUG~=1
                stoppreview(app.pixelfly_vid);
            end
        end

        % Value changed function: repumpSwitch
        function repumpSwitchValueChanged(app, event)
            app.toggleChannel(app.repumpSwitch);
        end

        % Value changed function: thorcam1PreviewToggle
        function thorcam1PreviewToggleValueChanged(app, event)
            stateThorcam = app.thorcam1PreviewToggle.Value;
            disp(stateThorcam)
            % Debug block: runs this block if DEBUG switch is on
            if app.DEBUG==1 && stateThorcam==1
                im = imagesc(app.thorcam1Axes,app.testGaussian);
                axis(im.Parent, 'image')
                axis(im.Parent, 'tight')
            elseif app.DEBUG==1 && stateThorcam==0
                im = image(app.thorcam1Axes,zeros(65,65));
            end
            
            
            if stateThorcam == 1 && app.DEBUG~=1
                im = image(app.thorcam1Axes,'CDataMapping','scaled');                     % Set axis to show the figure
                axis(im.Parent, 'image')                                                  % Configure axes aspect
                axis(im.Parent, 'tight')                                                  % Configure axes aspect
            
                [app.thorcam1, app.thorcam1ImageData, err] = InitiateCamera(im,1.7);            % Starts thorlabs camera live capture
                
                while stateThorcam == 1 || strcmp(err, uc480.Defines.Status.NO_SUCCESS)
                    [err,CamImage] = GetImage(app.thorcam1, app.thorcam1ImageData);
                    if ~isempty(CamImage)
                         im.CData = CamImage;
                         %contour()
                    end            
                    drawnow;
                end
                
                
            elseif stateThorcam == 0 && app.DEBUG~=1
                 im = imagesc(app.thorcam1Axes,zeros(65,65));
                 CloseCamera(app.thorcam1, app.thorcam1ImageData);
            end
        end

        % Value changed function: ProbeSwitch
        function ProbeSwitchValueChanged(app, event)
            
            app.toggleChannel(app.ProbeSwitch);
        end

        % Value changed function: ControlSwitch
        function ControlSwitchValueChanged(app, event)
            
            app.toggleChannel(app.ControlSwitch);
        end

        % Value changed function: IGBT_circ
        function IGBT_circValueChanged(app, event)
            app.setAO('CircCoil',0);
            app.toggleChannel(app.IGBT_circ);
            
        end

        % Value changed function: IGBT_rect
        function IGBT_rectValueChanged(app, event)
%             value = app.IGBT_rect.Value;
            app.setAO('RectCoil',0);
            app.toggleChannel(app.IGBT_rect);

        end

        % Value changed function: actionDropDown
        function actionDropDownValueChanged(app, event)
            global p
            value = app.actionDropDown.Value;
            amap=p.actionsMap(value);
            app.actionTable.ColumnName={amap{1:2:end}};
            app.actionTable.ColumnEditable=true;
            
            formats={amap{2:2:end}};
            app.actionTable.ColumnFormat=formats;
            dat=cell(1,length(formats));
            for ind=1:length(formats)
                if strcmpi('numeric',formats(ind))
                    dat{ind}=0;
                elseif strcmpi('char',formats(ind))
                    dat{ind}=' ';
                else
                    error('no such data type');
                end
                
            end
            if any(strcmpi(app.actionTable.ColumnName,'channel'))
            app.actionTable.ColumnFormat{find(strcmpi(app.actionTable.ColumnName,'channel'))}=p.ct.Row';
            end
            app.actionTable.Data=dat;
            
        end

        % Cell edit callback: actionTable
        function actionTableCellEdit(app, event)
            
            
        end

        % Button pushed function: AddactiontosequenceButton
        function AddactiontosequenceButtonPushed(app, event)
              app.addActionToSeq;           
%             colNames=app.actionTable.ColumnName;
%             colVals=app.actionTable.Data;
%             if ~iscell(colVals)
%                 colVals={colVals}
%             end
%             
%             actionName=app.actionDropDown.Value;
%             currentColNames={'action name'};
%             if ~isempty(colNames)
%                 if ~isempty(app.seqTable.ColumnName)
%                 
%                 currentColNames={app.seqTable.ColumnName{:}};
%                 else 
%                     currentColNames={'action name'};
%                 end
%             else 
%                 currentColNames={'action name'};
%             end
%             
%             for ind=1:length(colNames)
%                 if ~any(strcmpi(currentColNames,colNames{ind}))
%                     currentColNames{end+1}=colNames{ind};
%                 end
%             end
%             numRows=size(app.seqTable.Data,1);
%                 if numRows==0
%                     numRows=1;
%                 end
%             app.seqTable.ColumnName=currentColNames;
%             colFormats={'char'};
%             for ind=1:length(colVals)
%                 thisClass=class(colVals{ind});
%                 if strcmpi('double',thisClass)
%                     thisClass='numeric';
%                 end
%                 colFormats{end+1}=thisClass;
%             end
%             app.seqTable.ColumnFormat=colFormats;
%             currData=app.seqTable.Data;
%             if isempty(currData)
%                 app.seqTable.Data={actionName,colVals{:}};
%             else 
%                 app.seqTable.Data=currData{actionName,colVals{:}};
%             end
%             
%             for ind=1:length(colVals)
%                 app.seqTable.Data(numRows,find(strcmpi(currentColNames,colNames{ind})))=colVals(ind);
%             end
        end

        % Callback function
        function setButtonPushed(app, event)
            value = app.circularCoilCurrentIndicator.Value;
            value=str2double(value{1})*10/220;
            app.s.clear();
            app.s.addBlock({'setDigitalChannel','channel','IGBT_circ','value','high','duration',0});
            app.s.addBlock({'setAnalogChannel','channel','CircCoil','value',value,'duration',0});
            app.s.run();
            
        end

        % Button pushed function: labshutdownButton
        function labshutdownButtonPushed(app, event)
            labShutdown;
        end

        % Callback function
        function iceCoolingEmissionButtonPushed(app, event)
            global inst            
            cl=inst.Lasers('cooling');
            value = app.iceCoolingEmissionButton.Value;
            if value==1
                cl.setLaserStat('on');
            else 
                cl.setLaserStat('off');
            end
        end

        % Callback function
        function iceRepumpemissionButtonPushed(app, event)
            global inst            
            rp=inst.Lasers('cooling');
            value = app.iceRepumpemissionButton.Value;
            if value==1
                rp.setLaserStat('on');
            else 
                rp.setLaserStat('off');
            end
        end

        % Value changed function: coolingemissionButton
        function coolingemissionButtonValueChanged(app, event)
            
            global inst            
            cl=inst.Lasers('cooling');
            value = app.coolingemissionButton.Value;
            if value==1
                cl.setLaserStat('on');
                app.coolingemissionLamp.Color='g';
                app.coolinglockButton.Enable=true;
            else 
                cl.setLaserStat('off');
                app.coolingemissionLamp.Color=[0.94 0.94 0.94];
                app.coolinglockButton.Enable=false;
            end
        end

        % Value changed function: repumpemissionButton
        function repumpemissionButtonValueChanged(app, event)
            
             global inst            
            rp=inst.Lasers('repump');
            value = app.repumpemissionButton.Value;
            if value==1
                rp.setLaserStat('on');
                app.repumpemissionLamp.Color='g';
                app.repumplockButton.Enable=true;
            else 
                rp.setLaserStat('off');
                app.repumpemissionLamp.Color=[0.94 0.94 0.94];
                app.repumplockButton.Enable=false;
            end
        end

        % Button pushed function: moveupButton
        function moveupButtonPushed(app, event)
            
            if app.seqTableCurrentRow==1
                return
            else 
                app.s.switchBlocks(app.seqTableCurrentRow,app.seqTableCurrentRow-1);
                app.seqTableCurrentRow=app.seqTableCurrentRow-1;
                app.updateSeqTable;
            end
        end

        % Cell selection callback: seqTable
        function seqTableCellSelection(app, event)
            app.seqTableCurrentRow = event.Indices(1);
        end

        % Button pushed function: movedownButton
        function movedownButtonPushed(app, event)
            
            if app.seqTableCurrentRow==length(app.s.seq)
                return
            else 
                app.s.switchBlocks(app.seqTableCurrentRow,app.seqTableCurrentRow+1);
                app.seqTableCurrentRow=app.seqTableCurrentRow+1;
                app.updateSeqTable;
            end
        end

        % Button pushed function: removeButton
        function removeButtonPushed(app, event)
            app.s.remBlock(app.seqTableCurrentRow);
            app.updateSeqTable;
        end

        % Button pushed function: SavesequenceButton
        function SavesequenceButtonPushed(app, event)
            [file,path,indx] = uiputfile('*.mat','save sequence as...');
            seq=app.s;
            save(fullfile(path,file),'seq');
        end

        % Button pushed function: LoadsequenceButton
        function LoadsequenceButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if ~isequal(file,0)
            load(fullfile(path,file),'seq');
            app.s=seq;
            app.updateSeqTable;
            end
        end

        % Button pushed function: RunsequenceButton
        function RunsequenceButtonPushed(app, event)
            initinst
            initr
            app.s.run()
        end

        % Callback function
        function ICEconnectionButtonPushed(app, event)
            global inst
            gui_logger(app,'killing ICE_Control.exe');
            system('taskkill /FI "imagename eq ICE_control.exe"');
            gui_logger(app,'initiating ICE control');
            inst.Lasers=containers.Map({'cooling','repump'},{ICELaser('COM8',2,3,4),ICELaser('COM8',4,1,2)});
            assignin('base','inst',inst);
            updateICEStatus(app);
            
        end

        % Callback function
        function ZEEMANSwitchPushed(app, event)
        app.toggleChannel(app.ZEEMANSwitch);
        end

        % Value changing function: circCurrentSlider
        function circCurrentSliderValueChanging(app, event)
            changingValue = event.Value;
            app.circCurrentVal.BackgroundColor = 'white';
            app.circCurrentVal.Text = num2str(changingValue);
        end

        % Value changed function: circCurrentSlider
        function circCurrentSliderValueChanged(app, event)
            value = app.circCurrentSlider.Value;
            if app.IGBT_circ.Value ~= 1
               app.IGBT_circ.Value = 1;
               app.toggleChannel(app.IGBT_circ)
            end
            app.circCurrentVal.BackgroundColor = 'none';
            app.setAO('CircCoil',value*10/220);
            
        end

        % Button pushed function: InitButton
        function InitButtonPushed(app, event)
            wb = waitbar(1/20,'Initializing instruments connections. Please wait...');
            initr
            initinst
            close(wb);
        end

        % Value changed function: ZEEMANSwitch
        function ZEEMANSwitchValueChanged(app, event)
            value = app.ZEEMANSwitch.Value;
            app.toggleChannel(app.ZEEMANSwitch);
        end

        % Value changed function: ICEADDR0
        function ICEADDR0ValueChanged(app, event)
            value = app.ICEADDR0.Value;
            app.toggleChannel(app.ICEADDR0);
        end

        % Value changed function: ICEADDR1
        function ICEADDR1ValueChanged(app, event)
            value = app.ICEADDR1.Value;
            app.toggleChannel(app.ICEADDR1);
        end

        % Value changed function: ICEADDR2
        function ICEADDR2ValueChanged(app, event)
            value = app.ICEADDR2.Value;
            app.toggleChannel(app.ICEADDR2);
        end

        % Value changed function: ICEEVTTRIG
        function ICEEVTTRIGValueChanged(app, event)
            value = app.ICEEVTTRIG.Value;
            app.toggleChannel(app.ICEEVTTRIG);
        end

        % Value changed function: MW_CTL
        function MW_CTLValueChanged(app, event)
            value = app.MW_CTL.Value;
            app.toggleChannel(app.MW_CTL);
        end

        % Value changed function: MW_HLD
        function MW_HLDValueChanged(app, event)
            value = app.MW_HLD.Value;
            app.toggleChannel(app.MW_HLD);
        end

        % Value changed function: MWSourceSwitch
        function MWSourceSwitchValueChanged(app, event)
            value = app.MWSourceSwitch.Value;
            app.toggleChannel(app.MWSourceSwitch);
        end

        % Value changed function: DDS2_CTL
        function DDS2_CTLValueChanged(app, event)
            value = app.DDS2_CTL.Value;
            app.toggleChannel(app.DDS2_CTL);
        end

        % Value changed function: DDS2_HLD
        function DDS2_HLDValueChanged(app, event)
            value = app.DDS2_HLD.Value;
            app.toggleChannel(app.DDS2_HLD);
        end

        % Value changed function: ScopeTrigger
        function ScopeTriggerValueChanged(app, event)
            value = app.ScopeTrigger.Value;
            app.toggleChannel(app.ScopeTrigger);
        end

        % Value changed function: BIASPSU_TRIG
        function BIASPSU_TRIGValueChanged(app, event)
            value = app.BIASPSU_TRIG.Value;
            app.toggleChannel(app.BIASPSU_TRIG);
        end

        % Value changed function: DDS1_CTL
        function DDS1_CTLValueChanged(app, event)
            value = app.DDS1_CTL.Value;
            app.toggleChannel(app.DDS1_CTL);
        end

        % Value changed function: pixelflyPlaneTrig
        function pixelflyPlaneTrigValueChanged(app, event)
            value = app.pixelflyPlaneTrig.Value;
            app.toggleChannel(app.pixelflyPlaneTrig);
        end

        % Value changed function: pixelflyTopTrig
        function pixelflyTopTrigValueChanged(app, event)
            value = app.pixelflyTopTrig.Value;
            app.toggleChannel(app.pixelflyTopTrig);
        end

        % Value changed function: DispenserSwitch
        function DispenserSwitchValueChanged(app, event)
            value = app.DispenserSwitch.Value;
            app.toggleChannel(app.DispenserSwitch);
        end

        % Value changed function: BlueDTSwitch
        function BlueDTSwitchValueChanged(app, event)
            value = app.BlueDTSwitch.Value;
            app.toggleChannel(app.BlueDTSwitch);
        end

        % Value changed function: PurpleDTSwitch
        function PurpleDTSwitchValueChanged(app, event)
            value = app.PurpleDTSwitch.Value;
            app.toggleChannel(app.PurpleDTSwitch);
        end

        % Value changed function: DigOut5_2
        function DigOut5_2ValueChanged(app, event)
            value = app.DigOut5_2.Value;
            app.toggleChannel(app.DigOut5_2);
        end

        % Value changed function: DigOut6_2
        function DigOut6_2ValueChanged(app, event)
            value = app.DigOut6_2.Value;
            app.toggleChannel(app.DigOut6_2);
        end

        % Value changed function: DigOut7_2
        function DigOut7_2ValueChanged(app, event)
            value = app.DigOut7_2.Value;
            app.toggleChannel(app.DigOut7_2);
        end

        % Value changed function: DigOut8_2
        function DigOut8_2ValueChanged(app, event)
            value = app.DigOut8_2.Value;
            app.toggleChannel(app.DigOut8_2);
            
        end

        % Value changed function: DigOut9_2
        function DigOut9_2ValueChanged(app, event)
            value = app.DigOut9_2.Value;
            app.toggleChannel(app.DigOut9_2);
            
        end

        % Value changed function: DigOut10_2
        function DigOut10_2ValueChanged(app, event)
            value = app.DigOut10_2.Value;
            app.toggleChannel(app.DigOut10_2);
            
        end

        % Value changed function: DigOut11_2
        function DigOut11_2ValueChanged(app, event)
            value = app.DigOut11_2.Value;
            app.toggleChannel(app.DigOut11_2);
            
        end

        % Value changed function: DigOut12_2
        function DigOut12_2ValueChanged(app, event)
            value = app.DigOut12_2.Value;
            app.toggleChannel(app.DigOut12_2);
            
        end

        % Value changed function: DigOut13_2
        function DigOut13_2ValueChanged(app, event)
            value = app.DigOut13_2.Value;
            app.toggleChannel(app.DigOut13_2);
            
        end

        % Value changed function: DigOut14_2
        function DigOut14_2ValueChanged(app, event)
            value = app.DigOut14_2.Value;
            app.toggleChannel(app.DigOut14_2);
            
        end

        % Value changed function: DigOut15_2
        function DigOut15_2ValueChanged(app, event)
            value = app.DigOut15_2.Value;
            app.toggleChannel(app.DigOut15_2);
            
        end

        % Value changed function: DigOut16_2
        function DigOut16_2ValueChanged(app, event)
            value = app.DigOut16_2.Value;
            app.toggleChannel(app.DigOut16_2);
            
        end

        % Value changed function: DigOut17_2
        function DigOut17_2ValueChanged(app, event)
            value = app.DigOut17_2.Value;
            app.toggleChannel(app.DigOut17_2);
            
        end

        % Value changed function: DigOut18_2
        function DigOut18_2ValueChanged(app, event)
            value = app.DigOut18_2.Value;
            app.toggleChannel(app.DigOut18_2);
            
        end

        % Value changed function: DigOut19_2
        function DigOut19_2ValueChanged(app, event)
            value = app.DigOut19_2.Value;
            app.toggleChannel(app.DigOut19_2);
            
        end

        % Value changed function: DigOut20_2
        function DigOut20_2ValueChanged(app, event)
            value = app.DigOut20_2.Value;
            app.toggleChannel(app.DigOut20_2);
            
        end

        % Value changed function: DigOut21_2
        function DigOut21_2ValueChanged(app, event)
            value = app.DigOut21_2.Value;
            app.toggleChannel(app.DigOut21_2);
            
        end

        % Value changed function: DigOut22_2
        function DigOut22_2ValueChanged(app, event)
            value = app.DigOut22_2.Value;
            app.toggleChannel(app.DigOut22_2);
        end

        % Value changed function: DigOut23_2
        function DigOut23_2ValueChanged(app, event)
            value = app.DigOut23_2.Value;
            app.toggleChannel(app.DigOut23_2);
        end

        % Value changed function: DigOut24_2
        function DigOut24_2ValueChanged(app, event)
            value = app.DigOut24_2.Value;
            app.toggleChannel(app.DigOut24_2);
        end

        % Value changed function: DigOut25_2
        function DigOut25_2ValueChanged(app, event)
            value = app.DigOut25_2.Value;
            app.toggleChannel(app.DigOut25_2);
        end

        % Callback function
        function DigOut26_2ValueChanged(app, event)
            value = app.DigOut26_2.Value;
            app.toggleChannel(app.DigOut26_2);
        end

        % Callback function
        function DigOut27_2ValueChanged(app, event)
            value = app.DigOut27_2.Value;
            app.toggleChannel(app.DigOut27_2);
        end

        % Callback function
        function DigOut28_2ValueChanged(app, event)
            value = app.DigOut28_2.Value;
            app.toggleChannel(app.DigOut28_2);
        end

        % Callback function
        function DigOut29_2ValueChanged(app, event)
            value = app.DigOut29_2.Value;
            app.toggleChannel(app.DigOut29_2);
        end

        % Callback function
        function DigOut30_2ValueChanged(app, event)
            value = app.DigOut30_2.Value;
            app.toggleChannel(app.DigOut30_2);
        end

        % Callback function
        function DigOut31_2ValueChanged(app, event)
            value = app.DigOut31_2.Value;
            app.toggleChannel(app.DigOut31_2);
        end

        % Callback function
        function DigOut32_2ValueChanged(app, event)
            value = app.DigOut32_2.Value;
            app.toggleChannel(app.DigOut32_2);
        end

        % Value changed function: ExperimentDate
        function ExperimentDateValueChanged(app, event)
            value = app.ExperimentDate.Value;
            day = value(1:2);
            if str2num(day)<1 || str2num(day)>31
                error('day = %d, and it must be 1-31. Check input date!',day)
            end
            month = value(4:5);
            if str2num(month)<1 || str2num(month)>12
               error('month = %d, and it must be 1-12.  Check input date!',month)
            end
            year = value(7:10);
            if str2num(year)<2017 || str2num(year)>2023
                 error('year = %d, and it must be 2017-2023.  Check input date!',year)
            end
            app.dateHeader = sprintf('%s%s%s',day,month,year((end-1):end));
            expList = getExpList(app,year,month,day);
%             disp(expList)
            if ~iscell(expList)
                app.ExpTable.Data={};
                app.validdateLamp.Color='r';
                gui_logger(app,'not a valid date');
            else
                app.validdateLamp.Color='g';
                app.ExpTable.Data = expList;
                gui_logger(app,sprintf('opened date %d %d %d',day,month,year));
            end
            
        end

        % Cell selection callback: ExpTable
        function ExpTableCellSelection(app, event)
        clear global
        global p
        global r
        
        indic=event.Indices;
        row = indic(1,1); %col = indic(1,2);
        fileName = [app.expPath,'\',app.dateHeader,'_',app.ExpTable.Data{row,1},'.mat'];
        load(fileName,'r','p');
        load('plottingTable.mat');
        if ~any(strcmp(p.expName,plottingTable.Properties.RowNames))
            gui_logger(app,'no experiment plotter function exists for this experiment type. you should add it!');
        else 
            plotterName= plottingTable.plottingFunc(p.expName);
            eval([plotterName{1} '(app,p,r)']);
            gui_logger(app,['completed plotting: ' plotterName{1}]);
        end


        end

        % Cell edit callback: ExpTable
        function ExpTableCellEdit(app, event)
            
        end

        % Button pushed function: loadToWorkspaceButton
        function loadToWorkspaceButtonPushed(app, event)
            global r
            global p
            assignin('base','r',r);
            assignin('base','p',p);
        end

        % Button pushed function: loadexperimentButton
        function loadexperimentButtonPushed(app, event)
            uigetfile('*.mat');
        end

        % Value changed function: ICEconnectionButton
        function ICEconnectionButtonValueChanged(app, event)
            value = app.ICEconnectionButton.Value;
            global inst
            if value==1
                 
            gui_logger(app,'killing ICE_Control.exe');
            system('taskkill /FI "imagename eq ICE_control.exe"');
            gui_logger(app,'initiating ICE control');
            inst.Lasers=containers.Map({'cooling','repump'},{ICELaser('COM8',2,3,4),ICELaser('COM8',4,1,2)});
            assignin('base','inst',inst);
            updateICEStatus(app);
            else 
                fclose(inst.Lasers('cooling').s);
                app.coolingemissionLamp.Color=[0.94 0.94 0.94];
                app.repumpemissionLamp.Color=[0.94 0.94 0.94];
                 app.coolingemissionButton.Enable=false;
                 app.repumpemissionButton.Enable=false;
                 app.coolingtemplockLamp.Color=[0.94 0.94 0.94];
                 app.repumptemplockLamp.Color=[0.94 0.94 0.94];
                
            end
            
        end

        % Value changed function: repumplockButton
        function repumplockButtonValueChanged(app, event)
            global inst
            value = app.repumplockButton.Value;
            if value==1
                if inst.Lasers('repump').getTempLockStat
                    inst.Lasers('repump').setLaserServoStat('on');
                    [stat,rms]=inst.Lasers('repump').getFreqLockStat;
                    if stat==1
                        app.repumplockLamp.Color='g';
                    else
                        app.repumplockLamp.Color='r';
                    end
                    gui_logger(app,sprintf('repump laser lock stat %s, RMS error=%.2f [V]',stat,rms));
                end
            else 
                inst.Lasers('repump').setLaserServoStat('off');
                app.repumplockLamp.Color='r';
                gui_logger(app,sprintf('repump laser not locked'));
            end
        end

        % Value changed function: coolinglockButton
        function coolinglockButtonValueChanged(app, event)
            value = app.coolinglockButton.Value;
              global inst
            
            if value==1
                if inst.Lasers('cooling').getTempLockStat
                    inst.Lasers('cooling').setLaserServoStat('on');
                    [stat,rms]=inst.Lasers('cooling').getFreqLockStat;
                    if stat==1
                        app.coolinglockLamp.Color='g';
                    else
                        app.coolinglockLamp.Color='r';
                    end
                    gui_logger(app,sprintf('cooling laser lock stat %s, RMS error=%.2f [V]',stat,rms));
                end
            else 
                inst.Lasers('cooling').setLaserServoStat('off');
                app.coolinglockLamp.Color='r';
                gui_logger(app,sprintf('cooling laser not locked'));
            end
        end

        % Value changed function: startButton
        function startButtonValueChanged(app, event)
            value = app.startButton.Value;
            if value==1
                app.startButton.Text='stop';
            else
                app.startButton.Text='start...';
            end
            
            dipoleLooperFunc(app,value);


        end

        % Value changing function: ExperimentDate
        function ExperimentDateValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: OpenasfigureButton
        function OpenasfigureButtonPushed(app, event)
            global r
            global p
            if app.validfileLamp.Color(2)==1
                try
                    f = figure;
                    load('plottingTable.mat');
                    plotterName= plottingTable.plottingFunc(p.expName);
                    eval([plotterName{1} '(f,p,r,1)']);
%                     gui_logger(app,['completed plotting: ' plotterName{1}]);
                catch
                    gui_logger(app,'experiment plotter function dosent have the option to open as figure, see plotter_EddyCurrentDecay.m for an example');
                end
            else
                gui_logger(app,'no experiment plotter function exists for this experiment type. you should add it!');
            end

            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'ColdAtomsLab';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ChannelTogglerTab = uitab(app.TabGroup);
            app.ChannelTogglerTab.Title = 'Channel Toggler';
            app.AOMSwitchesPanel = uipanel(app.ChannelTogglerTab);
            app.AOMSwitchesPanel.Title = 'AOM Switches';
            app.repumpSwitch = uibutton(app.AOMSwitchesPanel, 'state');
            app.repumpSwitch.ValueChangedFcn = createCallbackFcn(app, @repumpSwitchValueChanged, true);
            app.repumpSwitch.Text = 'repumpSwitch';
            app.ControlSwitch = uibutton(app.AOMSwitchesPanel, 'state');
            app.ControlSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlSwitchValueChanged, true);
            app.ControlSwitch.Text = 'ControlSwitch';
            app.ProbeSwitch = uibutton(app.AOMSwitchesPanel, 'state');
            app.ProbeSwitch.ValueChangedFcn = createCallbackFcn(app, @ProbeSwitchValueChanged, true);
            app.ProbeSwitch.Text = 'ProbeSwitch';
            app.coolingSwitch = uibutton(app.AOMSwitchesPanel, 'state');
            app.coolingSwitch.ValueChangedFcn = createCallbackFcn(app, @coolingSwitchToggle, true);
            app.coolingSwitch.Text = 'coolingSwitch';
            app.ZEEMANSwitch = uibutton(app.AOMSwitchesPanel, 'state');
            app.ZEEMANSwitch.ValueChangedFcn = createCallbackFcn(app, @ZEEMANSwitchValueChanged, true);
            app.ZEEMANSwitch.Text = 'ZEEMANSwitch';
            app.AntiHHcurrentPanel = uipanel(app.ChannelTogglerTab);
            app.AntiHHcurrentPanel.Title = 'Anti-HH current';
            app.IGBT_circ = uibutton(app.AntiHHcurrentPanel, 'state');
            app.IGBT_circ.ValueChangedFcn = createCallbackFcn(app, @IGBT_circValueChanged, true);
            app.IGBT_circ.Text = 'IGBT_circ';
            app.IGBT_rect = uibutton(app.AntiHHcurrentPanel, 'state');
            app.IGBT_rect.ValueChangedFcn = createCallbackFcn(app, @IGBT_rectValueChanged, true);
            app.IGBT_rect.Text = 'IGBT_rect';
            app.circCurrentSliderLabel = uilabel(app.AntiHHcurrentPanel);
            app.circCurrentSliderLabel.Text = 'circCurrent';
            app.circCurrentSlider = uislider(app.AntiHHcurrentPanel);
            app.circCurrentSlider.Limits = [0 220];
            app.circCurrentSlider.MajorTicks = [0 50 100 150 220];
            app.circCurrentSlider.MajorTickLabels = {'0', '50', '100', '150', '220'};
            app.circCurrentSlider.ValueChangedFcn = createCallbackFcn(app, @circCurrentSliderValueChanged, true);
            app.circCurrentSlider.ValueChangingFcn = createCallbackFcn(app, @circCurrentSliderValueChanging, true);
            app.rectCurrentSliderLabel = uilabel(app.AntiHHcurrentPanel);
            app.rectCurrentSliderLabel.Text = 'rectCurrent';
            app.rectCurrentSlider = uislider(app.AntiHHcurrentPanel);
            app.rectCurrentSlider.Limits = [0 220];
            app.rectCurrentSlider.MajorTicks = [0 50 100 150 220];
            app.rectCurrentSlider.MajorTickLabels = {'0', '50', '100', '150', '220'};
            app.circCurrentVal = uilabel(app.AntiHHcurrentPanel);
            app.circCurrentVal.Text = '';
            app.circCurrentValLabel = uilabel(app.AntiHHcurrentPanel);
            app.circCurrentValLabel.Text = 'circCurrentVal';
            app.NotenabledLabel = uilabel(app.AntiHHcurrentPanel);
            app.NotenabledLabel.Text = 'Not enabled';
            app.ICEPanel = uipanel(app.ChannelTogglerTab);
            app.ICEPanel.Title = 'ICE';
            app.ICEADDR0 = uibutton(app.ICEPanel, 'state');
            app.ICEADDR0.ValueChangedFcn = createCallbackFcn(app, @ICEADDR0ValueChanged, true);
            app.ICEADDR0.Text = 'ICEADDR0';
            app.ICEADDR0.Value = true;
            app.ICEADDR2 = uibutton(app.ICEPanel, 'state');
            app.ICEADDR2.ValueChangedFcn = createCallbackFcn(app, @ICEADDR2ValueChanged, true);
            app.ICEADDR2.Text = 'ICEADDR2';
            app.ICEADDR2.Value = true;
            app.ICEEVTTRIG = uibutton(app.ICEPanel, 'state');
            app.ICEEVTTRIG.ValueChangedFcn = createCallbackFcn(app, @ICEEVTTRIGValueChanged, true);
            app.ICEEVTTRIG.Text = 'ICEEVTTRIG';
            app.ICEADDR1 = uibutton(app.ICEPanel, 'state');
            app.ICEADDR1.ValueChangedFcn = createCallbackFcn(app, @ICEADDR1ValueChanged, true);
            app.ICEADDR1.Text = 'ICEADDR1';
            app.ICEADDR1.Value = true;
            app.MWsourcePanel = uipanel(app.ChannelTogglerTab);
            app.MWsourcePanel.Title = 'MW source';
            app.MW_CTL = uibutton(app.MWsourcePanel, 'state');
            app.MW_CTL.ValueChangedFcn = createCallbackFcn(app, @MW_CTLValueChanged, true);
            app.MW_CTL.Text = 'MW_CTL';
            app.MWSourceSwitch = uibutton(app.MWsourcePanel, 'state');
            app.MWSourceSwitch.ValueChangedFcn = createCallbackFcn(app, @MWSourceSwitchValueChanged, true);
            app.MWSourceSwitch.Text = 'MWSourceSwitch';
            app.MW_HLD = uibutton(app.MWsourcePanel, 'state');
            app.MW_HLD.ValueChangedFcn = createCallbackFcn(app, @MW_HLDValueChanged, true);
            app.MW_HLD.Text = 'MW_HLD';
            app.ProbeDDSPanel = uipanel(app.ChannelTogglerTab);
            app.ProbeDDSPanel.Title = 'Probe DDS';
            app.DDS2_HLD = uibutton(app.ProbeDDSPanel, 'state');
            app.DDS2_HLD.ValueChangedFcn = createCallbackFcn(app, @DDS2_HLDValueChanged, true);
            app.DDS2_HLD.Text = 'DDS2_HLD';
            app.DDS2_CTL = uibutton(app.ProbeDDSPanel, 'state');
            app.DDS2_CTL.ValueChangedFcn = createCallbackFcn(app, @DDS2_CTLValueChanged, true);
            app.DDS2_CTL.Text = 'DDS2_CTL';
            app.ScopeTrigger = uibutton(app.ChannelTogglerTab, 'state');
            app.ScopeTrigger.ValueChangedFcn = createCallbackFcn(app, @ScopeTriggerValueChanged, true);
            app.ScopeTrigger.Text = 'ScopeTrigger';
            app.BIASPSU_TRIG = uibutton(app.ChannelTogglerTab, 'state');
            app.BIASPSU_TRIG.ValueChangedFcn = createCallbackFcn(app, @BIASPSU_TRIGValueChanged, true);
            app.BIASPSU_TRIG.Text = 'BIASPSU_TRIG';
            app.CoolingDDSPanel = uipanel(app.ChannelTogglerTab);
            app.CoolingDDSPanel.Title = 'Cooling DDS';
            app.DDS1_CTL = uibutton(app.CoolingDDSPanel, 'state');
            app.DDS1_CTL.ValueChangedFcn = createCallbackFcn(app, @DDS1_CTLValueChanged, true);
            app.DDS1_CTL.Text = 'DDS1_CTL';
            app.DDS1_HLD = uibutton(app.CoolingDDSPanel, 'state');
            app.DDS1_HLD.Text = 'DDS1_HLD (not assigned)';
            app.CameratriggerPanel = uipanel(app.ChannelTogglerTab);
            app.CameratriggerPanel.Title = 'Camera trigger';
            app.pixelflyPlaneTrig = uibutton(app.CameratriggerPanel, 'state');
            app.pixelflyPlaneTrig.ValueChangedFcn = createCallbackFcn(app, @pixelflyPlaneTrigValueChanged, true);
            app.pixelflyPlaneTrig.Text = 'pixelflyPlaneTrig';
            app.pixelflyTopTrig = uibutton(app.CameratriggerPanel, 'state');
            app.pixelflyTopTrig.ValueChangedFcn = createCallbackFcn(app, @pixelflyTopTrigValueChanged, true);
            app.pixelflyTopTrig.Text = 'pixelflyTopTrig';
            app.UnassignedchannelsPanel = uipanel(app.ChannelTogglerTab);
            app.UnassignedchannelsPanel.Title = 'Unassigned channels';
            app.DigOut12_2 = uibutton(app.UnassignedchannelsPanel, 'state');
            app.DigOut12_2.ValueChangedFcn = createCallbackFcn(app, @DigOut12_2ValueChanged, true);
            app.DigOut12_2.Text = 'DigOut12_2';
            app.DigOut13_2 = uibutton(app.UnassignedchannelsPanel, 'state');
            app.DigOut13_2.ValueChangedFcn = createCallbackFcn(app, @DigOut13_2ValueChanged, true);
            app.DigOut13_2.Text = 'DigOut13_2';
            app.DigOut14_2 = uibutton(app.UnassignedchannelsPanel, 'state');
            app.DigOut14_2.ValueChangedFcn = createCallbackFcn(app, @DigOut14_2ValueChanged, true);
            app.DigOut14_2.Text = 'DigOut14_2';
            app.DigOut5_2 = uibutton(app.UnassignedchannelsPanel, 'state');
            app.DigOut5_2.ValueChangedFcn = createCallbackFcn(app, @DigOut5_2ValueChanged, true);
            app.DigOut5_2.Text = 'DigOut5_2';
            app.DigOut15_2 = uibutton(app.UnassignedchannelsPanel, 'state');
            app.DigOut15_2.ValueChangedFcn = createCallbackFcn(app, @DigOut15_2ValueChanged, true);
            app.DigOut15_2.Text = 'DigOut15_2';
            app.DigOut6_2 = uibutton(app.UnassignedchannelsPanel, 'state');
            app.DigOut6_2.ValueChangedFcn = createCallbackFcn(app, @DigOut6_2ValueChanged, true);
            app.DigOut6_2.Text = 'DigOut6_2';
            app.DigOut16_2 = uibutton(app.UnassignedchannelsPanel, 'state');
            app.DigOut16_2.ValueChangedFcn = createCallbackFcn(app, @DigOut16_2ValueChanged, true);
            app.DigOut16_2.Text = 'DigOut16_2';
            app.DigOut7_2 = uibutton(app.UnassignedchannelsPanel, 'state');
            app.DigOut7_2.ValueChangedFcn = createCallbackFcn(app, @DigOut7_2ValueChanged, true);
            app.DigOut7_2.Text = 'DigOut7_2';
            app.DigOut19_2 = uibutton(app.UnassignedchannelsPanel, 'state');
            app.DigOut19_2.ValueChangedFcn = createCallbackFcn(app, @DigOut19_2ValueChanged, true);
            app.DigOut19_2.Text = 'DigOut19_2';
            app.DigOut8_2 = uibutton(app.UnassignedchannelsPanel, 'state');
            app.DigOut8_2.ValueChangedFcn = createCallbackFcn(app, @DigOut8_2ValueChanged, true);
            app.DigOut8_2.Text = 'DigOut8_2';
            app.DigOut18_2 = uibutton(app.UnassignedchannelsPanel, 'state');
            app.DigOut18_2.ValueChangedFcn = createCallbackFcn(app, @DigOut18_2ValueChanged, true);
            app.DigOut18_2.Text = 'DigOut18_2';
            app.DigOut9_2 = uibutton(app.UnassignedchannelsPanel, 'state');
            app.DigOut9_2.ValueChangedFcn = createCallbackFcn(app, @DigOut9_2ValueChanged, true);
            app.DigOut9_2.Text = 'DigOut9_2';
            app.DigOut10_2 = uibutton(app.UnassignedchannelsPanel, 'state');
            app.DigOut10_2.ValueChangedFcn = createCallbackFcn(app, @DigOut10_2ValueChanged, true);
            app.DigOut10_2.Text = 'DigOut10_2';
            app.DigOut20_2 = uibutton(app.UnassignedchannelsPanel, 'state');
            app.DigOut20_2.ValueChangedFcn = createCallbackFcn(app, @DigOut20_2ValueChanged, true);
            app.DigOut20_2.Text = 'DigOut20_2';
            app.DigOut11_2 = uibutton(app.UnassignedchannelsPanel, 'state');
            app.DigOut11_2.ValueChangedFcn = createCallbackFcn(app, @DigOut11_2ValueChanged, true);
            app.DigOut11_2.Text = 'DigOut11_2';
            app.DigOut21_2 = uibutton(app.UnassignedchannelsPanel, 'state');
            app.DigOut21_2.ValueChangedFcn = createCallbackFcn(app, @DigOut21_2ValueChanged, true);
            app.DigOut21_2.Text = 'DigOut21_2';
            app.DigOut17_2 = uibutton(app.UnassignedchannelsPanel, 'state');
            app.DigOut17_2.ValueChangedFcn = createCallbackFcn(app, @DigOut17_2ValueChanged, true);
            app.DigOut17_2.Text = 'DigOut17_2';
            app.DigOut22_2 = uibutton(app.UnassignedchannelsPanel, 'state');
            app.DigOut22_2.ValueChangedFcn = createCallbackFcn(app, @DigOut22_2ValueChanged, true);
            app.DigOut22_2.Text = 'DigOut22_2';
            app.DigOut23_2 = uibutton(app.UnassignedchannelsPanel, 'state');
            app.DigOut23_2.ValueChangedFcn = createCallbackFcn(app, @DigOut23_2ValueChanged, true);
            app.DigOut23_2.Text = 'DigOut23_2';
            app.DigOut24_2 = uibutton(app.UnassignedchannelsPanel, 'state');
            app.DigOut24_2.ValueChangedFcn = createCallbackFcn(app, @DigOut24_2ValueChanged, true);
            app.DigOut24_2.Text = 'DigOut24_2';
            app.DigOut25_2 = uibutton(app.UnassignedchannelsPanel, 'state');
            app.DigOut25_2.ValueChangedFcn = createCallbackFcn(app, @DigOut25_2ValueChanged, true);
            app.DigOut25_2.Text = 'DigOut25_2';
            app.DispenserSwitch = uibutton(app.ChannelTogglerTab, 'state');
            app.DispenserSwitch.ValueChangedFcn = createCallbackFcn(app, @DispenserSwitchValueChanged, true);
            app.DispenserSwitch.Text = 'DispenserSwitch';
            app.DipoleTrapPanel = uipanel(app.ChannelTogglerTab);
            app.DipoleTrapPanel.Title = 'Dipole Trap';
            app.BlueDTSwitch = uibutton(app.DipoleTrapPanel, 'state');
            app.BlueDTSwitch.ValueChangedFcn = createCallbackFcn(app, @BlueDTSwitchValueChanged, true);
            app.BlueDTSwitch.Text = 'BlueDTSwitch';
            app.PurpleDTSwitch = uibutton(app.DipoleTrapPanel, 'state');
            app.PurpleDTSwitch.ValueChangedFcn = createCallbackFcn(app, @PurpleDTSwitchValueChanged, true);
            app.PurpleDTSwitch.Text = 'PurpleDTSwitch';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Sequencer';
            app.AddactiontosequenceButton = uibutton(app.Tab3, 'push');
            app.AddactiontosequenceButton.ButtonPushedFcn = createCallbackFcn(app, @AddactiontosequenceButtonPushed, true);
            app.AddactiontosequenceButton.Text = 'Add action to sequence...';
            app.SavesequenceButton = uibutton(app.Tab3, 'push');
            app.SavesequenceButton.ButtonPushedFcn = createCallbackFcn(app, @SavesequenceButtonPushed, true);
            app.SavesequenceButton.Text = 'Save sequence';
            app.actionTable = uitable(app.Tab3);
            app.actionTable.ColumnName = '';
            app.actionTable.RowName = {};
            app.actionTable.CellEditCallback = createCallbackFcn(app, @actionTableCellEdit, true);
            app.actionDropDown_2Label = uilabel(app.Tab3);
            app.actionDropDown_2Label.Text = 'action';
            app.actionDropDown = uidropdown(app.Tab3);
            app.actionDropDown.Items = {'Load MOT', 'Release MOT', 'pause'};
            app.actionDropDown.ValueChangedFcn = createCallbackFcn(app, @actionDropDownValueChanged, true);
            app.actionDropDown.Value = 'Load MOT';
            app.seqTable = uitable(app.Tab3);
            app.seqTable.ColumnName = '';
            app.seqTable.RowName = {};
            app.seqTable.CellSelectionCallback = createCallbackFcn(app, @seqTableCellSelection, true);
            app.moveupButton = uibutton(app.Tab3, 'push');
            app.moveupButton.ButtonPushedFcn = createCallbackFcn(app, @moveupButtonPushed, true);
            app.moveupButton.Text = 'move up';
            app.movedownButton = uibutton(app.Tab3, 'push');
            app.movedownButton.ButtonPushedFcn = createCallbackFcn(app, @movedownButtonPushed, true);
            app.movedownButton.Text = 'move down';
            app.removeButton = uibutton(app.Tab3, 'push');
            app.removeButton.ButtonPushedFcn = createCallbackFcn(app, @removeButtonPushed, true);
            app.removeButton.Text = 'remove';
            app.LoadsequenceButton = uibutton(app.Tab3, 'push');
            app.LoadsequenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsequenceButtonPushed, true);
            app.LoadsequenceButton.Text = 'Load sequence';
            app.RunsequenceButton = uibutton(app.Tab3, 'push');
            app.RunsequenceButton.ButtonPushedFcn = createCallbackFcn(app, @RunsequenceButtonPushed, true);
            app.RunsequenceButton.Text = 'Run sequence...';
            app.ExperimentManagerTab = uitab(app.TabGroup);
            app.ExperimentManagerTab.Title = 'Experiment Manager';
            app.pTable = uitable(app.ExperimentManagerTab);
            app.pTable.ColumnName = {'param'; 'val'};
            app.pTable.RowName = {};
            app.paramstableLabel = uilabel(app.ExperimentManagerTab);
            app.paramstableLabel.Text = 'params table';
            app.loadexperimentButton = uibutton(app.ExperimentManagerTab, 'push');
            app.loadexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @loadexperimentButtonPushed, true);
            app.loadexperimentButton.Text = 'load experiment';
            app.saveexperimentButton = uibutton(app.ExperimentManagerTab, 'push');
            app.saveexperimentButton.Text = 'save experiment';
            app.UITable = uitable(app.ExperimentManagerTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.sequenceLabel = uilabel(app.ExperimentManagerTab);
            app.sequenceLabel.Text = 'sequence';
            app.loadseqButton = uibutton(app.ExperimentManagerTab, 'push');
            app.loadseqButton.Text = 'load seq';
            app.RunButton = uibutton(app.ExperimentManagerTab, 'push');
            app.RunButton.Text = 'Run';
            app.ParametersweepPanel = uipanel(app.ExperimentManagerTab);
            app.ParametersweepPanel.Title = 'Parameter sweep';
            app.outerloopparamDropDownLabel = uilabel(app.ParametersweepPanel);
            app.outerloopparamDropDownLabel.Text = 'outer loop param';
            app.outerloopparamDropDown = uidropdown(app.ParametersweepPanel);
            app.innerloopparamDropDownLabel = uilabel(app.ParametersweepPanel);
            app.innerloopparamDropDownLabel.Text = 'inner loop param';
            app.innerloopparamDropDown = uidropdown(app.ParametersweepPanel);
            app.outerstartvalEditFieldLabel = uilabel(app.ParametersweepPanel);
            app.outerstartvalEditFieldLabel.Text = 'outer start val';
            app.outerstartvalEditField = uieditfield(app.ParametersweepPanel, 'numeric');
            app.innerstartvalEditFieldLabel = uilabel(app.ParametersweepPanel);
            app.innerstartvalEditFieldLabel.Text = 'inner start val';
            app.innerstartvalEditField = uieditfield(app.ParametersweepPanel, 'numeric');
            app.outerendvalEditFieldLabel = uilabel(app.ParametersweepPanel);
            app.outerendvalEditFieldLabel.Text = 'outer end val';
            app.outerendvalEditField = uieditfield(app.ParametersweepPanel, 'numeric');
            app.outerendvalEditField_2Label = uilabel(app.ParametersweepPanel);
            app.outerendvalEditField_2Label.Text = 'outer end val';
            app.outerendvalEditField_2 = uieditfield(app.ParametersweepPanel, 'numeric');
            app.outernumstepsEditFieldLabel = uilabel(app.ParametersweepPanel);
            app.outernumstepsEditFieldLabel.Text = 'outer numsteps';
            app.outernumstepsEditField = uieditfield(app.ParametersweepPanel, 'numeric');
            app.innernumstepsEditFieldLabel = uilabel(app.ParametersweepPanel);
            app.innernumstepsEditFieldLabel.Text = 'inner  numsteps';
            app.innernumstepsEditField = uieditfield(app.ParametersweepPanel, 'numeric');
            app.PlottingTab = uitab(app.TabGroup);
            app.PlottingTab.Title = 'Plotting';
            app.ExperimentdateEditField_2Label = uilabel(app.PlottingTab);
            app.ExperimentdateEditField_2Label.Text = 'Experiment date';
            app.ExperimentDate = uieditfield(app.PlottingTab, 'text');
            app.ExperimentDate.ValueChangedFcn = createCallbackFcn(app, @ExperimentDateValueChanged, true);
            app.ExperimentDate.ValueChangingFcn = createCallbackFcn(app, @ExperimentDateValueChanging, true);
            app.ExperimentDate.Value = 'dd/mm/yyyy';
            app.ExpTable = uitable(app.PlottingTab);
            app.ExpTable.ColumnName = {'Index'; 'Name'; 'Time'; 'size[kB]'};
            app.ExpTable.RowName = {};
            app.ExpTable.CellEditCallback = createCallbackFcn(app, @ExpTableCellEdit, true);
            app.ExpTable.CellSelectionCallback = createCallbackFcn(app, @ExpTableCellSelection, true);
            app.PlotingAreaPanel = uipanel(app.PlottingTab);
            app.PlotingAreaPanel.Title = 'Ploting Area';
            app.validdateLampLabel = uilabel(app.PlottingTab);
            app.validdateLampLabel.Text = 'valid date';
            app.validdateLamp = uilamp(app.PlottingTab);
            app.validfileLampLabel = uilabel(app.PlottingTab);
            app.validfileLampLabel.Text = 'valid file';
            app.validfileLamp = uilamp(app.PlottingTab);
            app.loadToWorkspaceButton = uibutton(app.PlottingTab, 'push');
            app.loadToWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @loadToWorkspaceButtonPushed, true);
            app.loadToWorkspaceButton.Text = 'load current file to workspace...';
            app.OpenasfigureButton = uibutton(app.PlottingTab, 'push');
            app.OpenasfigureButton.ButtonPushedFcn = createCallbackFcn(app, @OpenasfigureButtonPushed, true);
            app.OpenasfigureButton.Text = 'Open as figure';
            app.DDSTab = uitab(app.TabGroup);
            app.DDSTab.Title = 'DDS';
            app.ShovachCOMDropDownLabel = uilabel(app.DDSTab);
            app.ShovachCOMDropDownLabel.Text = 'Shovach COM';
            app.ShovachCOMDropDown = uidropdown(app.DDSTab);
            app.ShovachCOMDropDown.Enable = 'off';
            app.TabGroup2 = uitabgroup(app.DDSTab);
            app.RampOptionsTab = uitab(app.TabGroup2);
            app.RampOptionsTab.Title = 'Ramp Options';
            app.StartFreqEditFieldLabel = uilabel(app.RampOptionsTab);
            app.StartFreqEditFieldLabel.Text = 'Start Freq';
            app.StartFreqEditField = uieditfield(app.RampOptionsTab, 'numeric');
            app.StartFreqEditField.Enable = 'off';
            app.EndFreqEditFieldLabel = uilabel(app.RampOptionsTab);
            app.EndFreqEditFieldLabel.Text = 'End Freq';
            app.EndFreqEditField = uieditfield(app.RampOptionsTab, 'numeric');
            app.EndFreqEditField.Enable = 'off';
            app.dfEditFieldLabel = uilabel(app.RampOptionsTab);
            app.dfEditFieldLabel.Enable = 'off';
            app.dfEditFieldLabel.Text = 'df';
            app.dfEditField = uieditfield(app.RampOptionsTab, 'numeric');
            app.dfEditField.Enable = 'off';
            app.EndFreqEditField_3Label = uilabel(app.RampOptionsTab);
            app.EndFreqEditField_3Label.Text = 'End Freq';
            app.EndFreqEditField_3 = uieditfield(app.RampOptionsTab, 'numeric');
            app.EndFreqEditField_3.Enable = 'off';
            app.ToggleRampButton = uibutton(app.RampOptionsTab, 'state');
            app.ToggleRampButton.Enable = 'off';
            app.ToggleRampButton.Text = 'Toggle Ramp';
            app.SetFreqTab = uitab(app.TabGroup2);
            app.SetFreqTab.Title = 'Set Freq';
            app.InitShovachButton = uibutton(app.DDSTab, 'push');
            app.InitShovachButton.Enable = 'off';
            app.InitShovachButton.Text = 'Init Shovach';
            app.ExtClockCheckBox = uicheckbox(app.DDSTab);
            app.ExtClockCheckBox.Enable = 'off';
            app.ExtClockCheckBox.Text = 'Ext Clock';
            app.LasercontrolTab = uitab(app.TabGroup);
            app.LasercontrolTab.Title = 'Laser control';
            app.ICELasersPanel = uipanel(app.LasercontrolTab);
            app.ICELasersPanel.Title = 'ICE Lasers';
            app.coolingemissionButton = uibutton(app.ICELasersPanel, 'state');
            app.coolingemissionButton.ValueChangedFcn = createCallbackFcn(app, @coolingemissionButtonValueChanged, true);
            app.coolingemissionButton.Enable = 'off';
            app.coolingemissionButton.Text = 'cooling emission';
            app.repumpemissionButton = uibutton(app.ICELasersPanel, 'state');
            app.repumpemissionButton.ValueChangedFcn = createCallbackFcn(app, @repumpemissionButtonValueChanged, true);
            app.repumpemissionButton.Enable = 'off';
            app.repumpemissionButton.Text = 'repump emission';
            app.ICEconnectionButton = uibutton(app.ICELasersPanel, 'state');
            app.ICEconnectionButton.ValueChangedFcn = createCallbackFcn(app, @ICEconnectionButtonValueChanged, true);
            app.ICEconnectionButton.Text = 'ICE connection';
            app.repumplockButton = uibutton(app.ICELasersPanel, 'state');
            app.repumplockButton.ValueChangedFcn = createCallbackFcn(app, @repumplockButtonValueChanged, true);
            app.repumplockButton.Enable = 'off';
            app.repumplockButton.Text = 'repump lock';
            app.coolinglockButton = uibutton(app.ICELasersPanel, 'state');
            app.coolinglockButton.ValueChangedFcn = createCallbackFcn(app, @coolinglockButtonValueChanged, true);
            app.coolinglockButton.Enable = 'off';
            app.coolinglockButton.Text = 'cooling lock';
            app.repumptemplockLampLabel = uilabel(app.ICELasersPanel);
            app.repumptemplockLampLabel.Text = 'repump temp lock';
            app.repumptemplockLamp = uilamp(app.ICELasersPanel);
            app.coolingtemplockLamp_2Label = uilabel(app.ICELasersPanel);
            app.coolingtemplockLamp_2Label.Text = 'cooling temp lock';
            app.coolingtemplockLamp = uilamp(app.ICELasersPanel);
            app.repumpemissionLampLabel = uilabel(app.ICELasersPanel);
            app.repumpemissionLampLabel.Text = 'repump emission';
            app.repumpemissionLamp = uilamp(app.ICELasersPanel);
            app.coolingemissionLamp_2Label = uilabel(app.ICELasersPanel);
            app.coolingemissionLamp_2Label.Text = 'cooling emission';
            app.coolingemissionLamp = uilamp(app.ICELasersPanel);
            app.repumplockLampLabel = uilabel(app.ICELasersPanel);
            app.repumplockLampLabel.Text = 'repump lock';
            app.repumplockLamp = uilamp(app.ICELasersPanel);
            app.coolinglockLampLabel = uilabel(app.ICELasersPanel);
            app.coolinglockLampLabel.Text = 'cooling lock';
            app.coolinglockLamp = uilamp(app.ICELasersPanel);
            app.TAPanel = uipanel(app.LasercontrolTab);
            app.TAPanel.Title = 'TA ';
            app.TAONLampLabel = uilabel(app.TAPanel);
            app.TAONLampLabel.Text = 'TA ON';
            app.TAONLamp = uilamp(app.TAPanel);
            app.cameraTab = uitab(app.TabGroup);
            app.cameraTab.Title = 'Cameras';
            app.pixelflyAxes = uiaxes(app.cameraTab);
            title(app.pixelflyAxes, 'PixelFly Top')
            xlabel(app.pixelflyAxes, 'X')
            ylabel(app.pixelflyAxes, 'Y')
            app.thorcam1Axes = uiaxes(app.cameraTab);
            title(app.thorcam1Axes, 'Pixelfly Plane')
            xlabel(app.thorcam1Axes, 'X')
            ylabel(app.thorcam1Axes, 'Y')
            app.LabelNumericEditField = uilabel(app.cameraTab);
            app.LabelNumericEditField.Text = 'Exposure [uS]';
            app.pixeflyExposureEdit = uieditfield(app.cameraTab, 'numeric');
            app.pixeflyExposureEdit.ValueChangedFcn = createCallbackFcn(app, @pixeflyExposureEditValueChanged, true);
            app.Label = uilabel(app.cameraTab);
            app.Label.Text = 'Exposure [uS]';
            app.thorcam1ExposureEdit = uieditfield(app.cameraTab, 'numeric');
            app.thorcam1ExposureEdit.Limits = [0 Inf];
            app.pixelflyPreviewToggle = uibutton(app.cameraTab, 'state');
            app.pixelflyPreviewToggle.ValueChangedFcn = createCallbackFcn(app, @pixelflyPreviewToggleValueChanged, true);
            app.pixelflyPreviewToggle.Text = 'Pixelfly preview';
            app.thorcam1PreviewToggle = uibutton(app.cameraTab, 'state');
            app.thorcam1PreviewToggle.ValueChangedFcn = createCallbackFcn(app, @thorcam1PreviewToggleValueChanged, true);
            app.thorcam1PreviewToggle.Text = 'Thorcam preview';
            app.ImageInfoTextAreaLabel = uilabel(app.cameraTab);
            app.ImageInfoTextAreaLabel.Text = 'Image Info';
            app.ImageInfoTextArea = uitextarea(app.cameraTab);
            app.ImageInfoTextArea.Value = {''; ''};
            app.dipoletraplooperTab = uitab(app.TabGroup);
            app.dipoletraplooperTab.Title = 'dipole trap looper';
            app.startButton = uibutton(app.dipoletraplooperTab, 'state');
            app.startButton.ValueChangedFcn = createCallbackFcn(app, @startButtonValueChanged, true);
            app.startButton.Text = 'start...';
            app.topDipoleView = uiaxes(app.dipoletraplooperTab);
            title(app.topDipoleView, 'top')
            xlabel(app.topDipoleView, 'X')
            ylabel(app.topDipoleView, 'Y')
            app.sideDipoleView = uiaxes(app.dipoletraplooperTab);
            title(app.sideDipoleView, 'side')
            xlabel(app.sideDipoleView, 'X')
            ylabel(app.sideDipoleView, 'Y')
            app.LoadMOTButton = uibutton(app.UIFigure, 'push');
            app.LoadMOTButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMOTButtonPushed, true);
            app.LoadMOTButton.Text = 'Load MOT';
            app.UnloadMOTButton = uibutton(app.UIFigure, 'push');
            app.UnloadMOTButton.ButtonPushedFcn = createCallbackFcn(app, @UnloadMOTButtonPushed, true);
            app.UnloadMOTButton.Text = 'Unload MOT';
            app.DEBUGSwitchLabel = uilabel(app.UIFigure);
            app.DEBUGSwitchLabel.Text = 'DEBUG';
            app.DEBUGSwitch = uiswitch(app.UIFigure, 'toggle');
            app.DEBUGSwitch.ValueChangedFcn = createCallbackFcn(app, @DEBUGSwitchValueChanged, true);
            app.DEBUGSwitch.Value = 'On';
            app.labshutdownButton = uibutton(app.UIFigure, 'push');
            app.labshutdownButton.ButtonPushedFcn = createCallbackFcn(app, @labshutdownButtonPushed, true);
            app.labshutdownButton.Text = 'lab shutdown';
            app.InitButton = uibutton(app.UIFigure, 'push');
            app.InitButton.ButtonPushedFcn = createCallbackFcn(app, @InitButtonPushed, true);
            app.InitButton.Text = 'Init';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.LogBox = uilistbox(app.UIFigure);
            app.LogBox.Items = {};
            app.LogBox.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = main_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end