classdef MPTGaugeReader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        setfilelocationButton      matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        COMPORTDropDownLabel       matlab.ui.control.Label
        COMPORTDropDown            matlab.ui.control.DropDown
        STOPButton                 matlab.ui.control.Button
        readingLampLabel           matlab.ui.control.Label
        readingLamp                matlab.ui.control.Lamp
        timescaleSwitchLabel       matlab.ui.control.Label
        timescaleSwitch            matlab.ui.control.Switch
        LastReadingEditFieldLabel  matlab.ui.control.Label
        LastReadingEditField       matlab.ui.control.NumericEditField
        TorrLabel                  matlab.ui.control.Label
    end


    properties (Access = private)
        %gauge=MPT200; % Description
        gauge;
        run=1 % Description
        tmr % timer object for reading 
        tmr2;%
        path % Description
        scale % 1X2 vector with min/max of plot
    end

    properties (Access = public)
        pressureVec=zeros(1,1000); % 
        timeVec=linspace(1,1000,1000);
        
    end

    methods (Access = private)
    
        function pressure = readPressure(app)
            try
            pressure=app.gauge.getPressure;
            if app.readingLamp.Color==[0,1,0]
                app.readingLamp.Color=[0.9,0.9,0.9];
            else 
                app.readingLamp.Color=[0,1,0];
            end
            catch
                pressure=nan;
                disp('cannot read pressure!');
            end
            try
            savePressureFile(now(),pressure,app.path);
            catch
                disp('error in write')
            end
            dat=readPressureFile(app.path);
            currentPressure=dat(end,2);
            app.LastReadingEditField.Value=currentPressure;
            if strcmpi(app.timescaleSwitch.Value,'short') && length(dat(:,1))>20
                plot(app.UIAxes,dat(end-20:end,1),dat(end-20:end,2),'o-');            
                xlim(app.UIAxes,[min(dat(end-20:end,1)),max(dat(end-20:end,1))]);
                ylim(app.UIAxes,[min(dat(end-20:end,2))*0.9,max(dat(end-20:end,2))*1.1]);
                
            else
                plot(app.UIAxes,dat(:,1),dat(:,2),'o-');            
                xlim(app.UIAxes,[min(dat(:,1)),max(dat(:,1))]);
                ylim(app.UIAxes,[min(dat(:,2))*0.9,max(dat(:,2))*1.1]);
            end
%             xlim(app.UIAxes,[app.scale(1,1),app.scale(1,2)]);
%             ylim(app.UIAxes,[app.scale(2,1),app.scale(2,2)]);

            datetick(app.UIAxes,'x','HH:MM:ss')
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            plot(app.UIAxes,app.timeVec,app.pressureVec,'o');
            
            xlabel(app.UIAxes,'Time')
            ylabel(app.UIAxes,'Pressure [Torr]')
%             app.tmr=timer;
%             app.tmr.ErrorFcn       = @myerror;
%             app.tmr.TimerFcn=@(~,thisEvent) app.readPressure;
%             app.tmr.Period=2;
%             app.tmr.ExecutionMode='fixedSpacing';
%             start(app.tmr);
            ports=getAvailableComPort;
            app.COMPORTDropDown.Items=ports';
           
%             function myerror(h,~)
%              stop(app.tmr)
%              start(app.tmr)
%             end
        end

        % Callback function
        function startstopButtonPushed(app, event)
         app.path=uigetdir
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.tmr)
            delete(app)
            
        end

        % Button pushed function: setfilelocationButton
        function setfilelocationButtonPushed(app, event)
            app.path=uigetdir
            app.StartButton.Enable='on';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.gauge=MPT200(app.COMPORTDropDown.Value);
             app.tmr=timer;
            app.tmr.ErrorFcn       = @myerror;
            app.tmr.TimerFcn=@(~,thisEvent) app.readPressure;
            app.tmr.Period=2;
            app.tmr.ExecutionMode='fixedSpacing';
            start(app.tmr);
            
             function myerror(h,~)
             stop(app.tmr)
             start(app.tmr)
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            stop(app.tmr)
        end

        % Value changed function: timescaleSwitch
        function timescaleSwitchValueChanged(app, event)
            value = app.timescaleSwitch.Value;
            if strcmpi(value,'short')
                app.scale='short';
            else 
                    app.scale='long';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'MPT200 Gauge reading')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.setfilelocationButton = uibutton(app.UIFigure, 'push');
            app.setfilelocationButton.ButtonPushedFcn = createCallbackFcn(app, @setfilelocationButtonPushed, true);
            app.setfilelocationButton.Text = 'set file location';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start...';
            app.COMPORTDropDownLabel = uilabel(app.UIFigure);
            app.COMPORTDropDownLabel.Text = 'COMPORT';
            app.COMPORTDropDown = uidropdown(app.UIFigure);
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.readingLampLabel = uilabel(app.UIFigure);
            app.readingLampLabel.Text = 'reading';
            app.readingLamp = uilamp(app.UIFigure);
            app.timescaleSwitchLabel = uilabel(app.UIFigure);
            app.timescaleSwitchLabel.Text = 'time scale';
            app.timescaleSwitch = uiswitch(app.UIFigure, 'slider');
            app.timescaleSwitch.Items = {'long', 'short'};
            app.timescaleSwitch.ValueChangedFcn = createCallbackFcn(app, @timescaleSwitchValueChanged, true);
            app.timescaleSwitch.Value = 'long';
            app.LastReadingEditFieldLabel = uilabel(app.UIFigure);
            app.LastReadingEditFieldLabel.Text = 'Last Reading';
            app.LastReadingEditField = uieditfield(app.UIFigure, 'numeric');
            app.LastReadingEditField.ValueDisplayFormat = '%2.2e';
            app.TorrLabel = uilabel(app.UIFigure);
            app.TorrLabel.Text = 'Torr';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MPTGaugeReader

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end