classdef TitanGaugeReader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        SetfilelocationButton  matlab.ui.control.Button
    end


    properties (Access = private)
        gauge=TitanGauge; % Description
        run=1 % Description
        tmr % timer object for reading 
        tmr2;%
        path;
    end

    properties (Access = public)
        pressureVec=zeros(1,1000); % 
        timeVec=linspace(1,1000,1000);
        
    end

    methods (Access = private)
    
        function pressure = readPressure(app)
            try
            pressure=app.gauge.getPressure;
            catch
                pressure=nan;
                disp('cannot read pressure!');
            end
            try
            saveIonPressureFile(now(),pressure,app.path);
            catch
                disp('error in write')
            end
            dat=readIonPressureFile(app.path);
            plot(app.UIAxes,dat(:,1),dat(:,2),'o-');
            xlim(app.UIAxes,[min(dat(:,1)),max(dat(:,1))]);
            ylim(app.UIAxes,[min(dat(:,2))*0.9,max(dat(:,2))*1.1]);
            datetick(app.UIAxes,'x','HH:MM:ss')
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            plot(app.UIAxes,app.timeVec,app.pressureVec,'o');
            
            xlabel(app.UIAxes,'Time')
            ylabel(app.UIAxes,'Pressure [Torr]')
            app.tmr=timer;
            app.tmr.ErrorFcn       = @myerror;
            app.tmr.TimerFcn=@(~,thisEvent) app.readPressure;
            app.tmr.Period=2;
            app.tmr.ExecutionMode='fixedSpacing';
            start(app.tmr);
            
            function myerror(h,~)
             stop(app.tmr)
             start(app.tmr)
            end
        end

        % Callback function
        function startstopButtonPushed(app, event)
         
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.tmr)
            delete(app)
            
        end

        % Button pushed function: SetfilelocationButton
        function SetfilelocationButtonPushed(app, event)
            app.path=uigetdir
            app.StartButton.Enable='on'; 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Titan Ion Pump Gauge reading')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SetfilelocationButton = uibutton(app.UIFigure, 'push');
            app.SetfilelocationButton.ButtonPushedFcn = createCallbackFcn(app, @SetfilelocationButtonPushed, true);
            app.SetfilelocationButton.Text = 'Set file location';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TitanGaugeReader

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end