classdef ttProcessor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ttReaderUIFigure                matlab.ui.Figure
        measuringLampLabel              matlab.ui.control.Label
        measuringLamp                   matlab.ui.control.Lamp
        TimeTaggerprocessorserverLabel  matlab.ui.control.Label
        gateTimenSEditFieldLabel        matlab.ui.control.Label
        gateTimenSEditField             matlab.ui.control.NumericEditField
        cycleTimenSEditFieldLabel       matlab.ui.control.Label
        cycleTimenSEditField            matlab.ui.control.NumericEditField
        cycleNumberEditFieldLabel       matlab.ui.control.Label
        cycleNumberEditField            matlab.ui.control.NumericEditField
        bunchNumberEditFieldLabel       matlab.ui.control.Label
        bunchNumberEditField            matlab.ui.control.NumericEditField
        TestsignalsPanel                matlab.ui.container.Panel
        testsignal1CheckBox             matlab.ui.control.CheckBox
        testsignal2CheckBox             matlab.ui.control.CheckBox
        testsignal3CheckBox             matlab.ui.control.CheckBox
        FileinfoPanel                   matlab.ui.container.Panel
        OpensavelocationButton          matlab.ui.control.Button
        currentfilesizeMBLabel          matlab.ui.control.Label
        currentSizeEdit                 matlab.ui.control.NumericEditField
        currentfilenameEditFieldLabel   matlab.ui.control.Label
        currentfilenameEditField        matlab.ui.control.EditField
        jobqueueTextAreaLabel           matlab.ui.control.Label
        jobqueueTextArea                matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        
        rawDat%
        tmr % reading the tt
        bunchNum=0;
        measurementParams=struct('gateTime',1,'cycleTime',1)
        saveStatus % contains information on the tt_dumpManager last run
        ctr % counter measurement


    end
    
    properties (Access = public)
        autoDumpFlag % automatically dump a file at set size
        currDataFile %
        dump % Description
        tt % time tagger
        currFileNum=1 % Description
        nextDumpFlag %request next dump file
        preProcQueue % queue to process the bin files into MIT compatible files
        parPool % parallel pool processing object
        jobs={}% the list of parallel jobs
    end
    
    methods (Access = private)
        
        function status = saveData(app,obj,event)
            %status contains information regarding current saving status: file name, size etc...
            try
            toggleLamp(app);
            end
        end
        
        
        function toggleLamp(app)
            if all(app.measuringLamp.Color==[0,1,0])
                app.measuringLamp.Color=[0.94,0.94,0.94];
            else
                app.measuringLamp.Color=[0,1,0];
            end
        end     
        function num = getCurrentFileNum(app)
            num=1;
        end
        
        function folder = getCurrentSaveFolder(app)
            basePath=fullfile(fileparts(which('basicImports')),'..','..','..','Measurements');
            month=datestr(datetime('now'),'mm');
            year=datestr(datetime('now'),'YYYY');
            day=datestr(datetime('now'),'dd');
            folder=fullfile(basePath,year,month,day,'tt');
        end
    end
    
    methods (Access = public)
        
        function toggleMeas(app,state)
            global p
            app.StartMeasurementButton.Value=state;
            if state
                app.StartMeasurementButton.Text='Stop Measurement';
                app.nextDumpFlag=1;
                start(app.tmr);
            else
                app.StartMeasurementButton.Text='Start Measurement';
                app.measuringLamp.Color=[0.94,0.94,0.94];
                if strcmpi(class(app.dump),'TTDump')
                    app.dump.stop;
                    folder=getCurrentSaveFolder(app);
                    [~,this_file_name]=getNextDumpFileName(folder);
                    if exist('p','var') && ~strcmpi(this_file_name,'')
                        parts=split(this_file_name,'.');
                        fn=parts{1};
                        
                        save(fullfile(folder,['p_' fn(4:end) '.mat']),'p');
                        %                         queue=app.preProcQueue;
                        if app.parallelpreprocCheckBox.Value
                        app.jobs{end+1}=parfeval(@binFileToMatQueue, 0,fullfile(folder,this_file_name), app.preProcQueue); 
                        disp(sprintf("sent %s to queue\n",this_file_name));
                        end
                        %                         listener = afterEach(app.preProcQueue, @binToMatDone);
                        %                         for ind=1:length(app.jobs)
                        %                             if strcmpi(app.jobs{ind}.State,'finished')
                        %                                 app.jobs{ind}=[];
                        %                             end
                        %                         end
                        
                        %                         end
                        
                    else
                        warning('no p variable. not saving a .mat file')
                    end
                    
                end
                %                 app.currFileNum=app.currFileNum+1;
                app.nextDumpFlag=1;
                
                
                stop(app.tmr);
                
            end
            
        end
        
        function setMeasurementParams(app,params)
            if isfield(params,'gateTime')
                app.gateTimenSEditField.Value=params.gateTime;
                app.measurementParams.gateTime=params.gateTime;
            end
            if isfield(params,'cycleTime')
                app.cycleTimenSEditField.Value=params.cycleTime;
                app.measurementParams.cycleTime=params.cycleTime;
            end
            
            
        end
        
        function params = getMeasurementParams(app)
            params=app.measurementParams;
        end
        
        function bunchNum = getBunchNum(app)
            bunchNum=app.bunchNum;
            
        end
        
        function newBunchNum= incBunchNum(app)
            app.bunchNum=app.bunchNum+1;
            newBunchNum=app.bunchNum;
        end
        
        function bunchSize = getBunchSize(app)
            bunchSize=app.bunchSize;
        end
        
        function success = nextDump(app)
            app.dump.stop;
            app.nextDumpFlag=1;
            tt_dumpManager(app);
            folder=getCurrentSaveFolder(app);
            [prev_name,this_file_name]=getNextDumpFileName(folder);
            if isempty(this_file_name)
                this_file_name=prev_name;
            end
            
            if exist('p','var') && ~strcmpi(this_file_name,'')
                parts=split(this_file_name,'.');
                fn=parts{1};
                
                save(fullfile(folder,['p_' fn(4:end) '.mat']),'p');
                %                         queue=app.preProcQueue;
                
                
            end
            if app.parallelpreprocCheckBox.Value
            app.jobs{end+1}=parfeval(@binFileToMatQueue, 0,fullfile(folder,this_file_name), app.preProcQueue);
            disp(sprintf("sent %s to queue\n",this_file_name));
            end
            disp('next dump successful');
            success=1;
%             app.checkAndClearQueue()
        end
        
        
        function status = checkAndClearQueue(app)
            
            jctr=0;
            finishedJobs=[];
            unfinishedJobs=[];
            errorJobs=[];
            for ind=1:length(app.jobs)
                try
                    fetchOutputs(app.jobs{ind});
                catch
                    disp('error in job %s',ind);
                end
                thisJob=app.jobs{ind};
                
                if strcmpi(thisJob.State,'finished') && isempty(thisJob.Error)
                    jctr=jctr+1;
                    finishedJobs(end+1)=ind;
                    
                else
                    if ~isempty(app.jobs{ind}.Error)
                        errorJobs(end+1)=ind;
                    end
                    unfinishedJobs(end+1)=ind;
                end
                
                
                %                 if strcmpi(thisJob.State,'')
                %                     disp(ind);
                %                 end
                
            end
            text=sprintf('There are %d jobs in the queue\n',length(app.jobs));
            text=[text sprintf('There are %d finished jobs in the queue\n',jctr)];
            
            app.jobqueueTextArea.Value=sprintf(['Unfinished jobs:%s\n' 'Finished jobs: %s\n' 'error jobs: %s\n'],num2str(unfinishedJobs),num2str(finishedJobs),num2str(errorJobs));
%             app.jobs={app.jobs{unfinishedJobs}};
            status=1;
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ttReaderUIFigure = uifigure('Visible', 'off');
            app.ttReaderUIFigure.Name = 'ttReader';
            app.measuringLampLabel = uilabel(app.ttReaderUIFigure);
            app.measuringLampLabel.Text = 'measuring...';
            app.measuringLamp = uilamp(app.ttReaderUIFigure);
            app.TimeTaggerprocessorserverLabel = uilabel(app.ttReaderUIFigure);
            app.TimeTaggerprocessorserverLabel.Text = 'TimeTagger processor server';
            app.gateTimenSEditFieldLabel = uilabel(app.ttReaderUIFigure);
            app.gateTimenSEditFieldLabel.Enable = 'off';
            app.gateTimenSEditFieldLabel.Text = 'gate Time [nS]';
            app.gateTimenSEditField = uieditfield(app.ttReaderUIFigure, 'numeric');
            app.gateTimenSEditField.Editable = 'off';
            app.gateTimenSEditField.Enable = 'off';
            app.cycleTimenSEditFieldLabel = uilabel(app.ttReaderUIFigure);
            app.cycleTimenSEditFieldLabel.Enable = 'off';
            app.cycleTimenSEditFieldLabel.Text = 'cycle Time [nS]';
            app.cycleTimenSEditField = uieditfield(app.ttReaderUIFigure, 'numeric');
            app.cycleTimenSEditField.Editable = 'off';
            app.cycleTimenSEditField.Enable = 'off';
            app.cycleNumberEditFieldLabel = uilabel(app.ttReaderUIFigure);
            app.cycleNumberEditFieldLabel.Enable = 'off';
            app.cycleNumberEditFieldLabel.Text = 'cycle Number';
            app.cycleNumberEditField = uieditfield(app.ttReaderUIFigure, 'numeric');
            app.cycleNumberEditField.Editable = 'off';
            app.cycleNumberEditField.Enable = 'off';
            app.bunchNumberEditFieldLabel = uilabel(app.ttReaderUIFigure);
            app.bunchNumberEditFieldLabel.Enable = 'off';
            app.bunchNumberEditFieldLabel.Text = 'bunch Number';
            app.bunchNumberEditField = uieditfield(app.ttReaderUIFigure, 'numeric');
            app.bunchNumberEditField.Editable = 'off';
            app.bunchNumberEditField.Enable = 'off';
            app.TestsignalsPanel = uipanel(app.ttReaderUIFigure);
            app.TestsignalsPanel.Title = 'Test signals';
            app.testsignal1CheckBox = uicheckbox(app.TestsignalsPanel);
            app.testsignal1CheckBox.Text = 'test signal 1';
            app.testsignal2CheckBox = uicheckbox(app.TestsignalsPanel);
            app.testsignal2CheckBox.Text = 'test signal 2';
            app.testsignal3CheckBox = uicheckbox(app.TestsignalsPanel);
            app.testsignal3CheckBox.Text = 'test signal 3';
            app.FileinfoPanel = uipanel(app.ttReaderUIFigure);
            app.FileinfoPanel.Title = 'File info';
            app.OpensavelocationButton = uibutton(app.FileinfoPanel, 'push');
            app.OpensavelocationButton.Text = 'Open save location...';
            app.currentfilesizeMBLabel = uilabel(app.FileinfoPanel);
            app.currentfilesizeMBLabel.Text = 'current file size [MB]';
            app.currentSizeEdit = uieditfield(app.FileinfoPanel, 'numeric');
            app.currentSizeEdit.ValueDisplayFormat = '%.2f';
            app.currentSizeEdit.Editable = 'off';
            app.currentfilenameEditFieldLabel = uilabel(app.FileinfoPanel);
            app.currentfilenameEditFieldLabel.Text = 'current file name';
            app.currentfilenameEditField = uieditfield(app.FileinfoPanel, 'text');
            app.currentfilenameEditField.Editable = 'off';
            app.jobqueueTextAreaLabel = uilabel(app.ttReaderUIFigure);
            app.jobqueueTextAreaLabel.Text = 'job queue';
            app.jobqueueTextArea = uitextarea(app.ttReaderUIFigure);
            % Show the figure after all components are created
            app.ttReaderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ttProcessor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ttReaderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ttReaderUIFigure)
        end
    end
end