classdef ttReader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ttReaderUIFigure               matlab.ui.Figure
        resettimetaggerButton          matlab.ui.control.Button
        measuringLampLabel             matlab.ui.control.Label
        measuringLamp                  matlab.ui.control.Lamp
        TimeTaggerreadoutserverLabel   matlab.ui.control.Label
        readoutperiodsSpinnerLabel     matlab.ui.control.Label
        readoutperiodsSpinner          matlab.ui.control.Spinner
        lastreadoutEditFieldLabel      matlab.ui.control.Label
        lastreadoutEditField           matlab.ui.control.EditField
        StartMeasurementButton         matlab.ui.control.StateButton
        gateTimenSEditFieldLabel       matlab.ui.control.Label
        gateTimenSEditField            matlab.ui.control.NumericEditField
        cycleTimenSEditFieldLabel      matlab.ui.control.Label
        cycleTimenSEditField           matlab.ui.control.NumericEditField
        cycleNumberEditFieldLabel      matlab.ui.control.Label
        cycleNumberEditField           matlab.ui.control.NumericEditField
        bunchNumberEditFieldLabel      matlab.ui.control.Label
        bunchNumberEditField           matlab.ui.control.NumericEditField
        Clickrate10mSPanel             matlab.ui.container.Panel
        clickratechan3EditFieldLabel   matlab.ui.control.Label
        clickratechan3EditField        matlab.ui.control.NumericEditField
        clickratechan2EditFieldLabel   matlab.ui.control.Label
        clickratechan2EditField        matlab.ui.control.NumericEditField
        clickratechan1EditFieldLabel   matlab.ui.control.Label
        clickratechan1EditField        matlab.ui.control.NumericEditField
        rateMeasCheckBox               matlab.ui.control.CheckBox
        TestsignalsPanel               matlab.ui.container.Panel
        testsignal1CheckBox            matlab.ui.control.CheckBox
        testsignal2CheckBox            matlab.ui.control.CheckBox
        testsignal3CheckBox            matlab.ui.control.CheckBox
        FileinfoPanel                  matlab.ui.container.Panel
        OpensavelocationButton         matlab.ui.control.Button
        currentfilesizeMBLabel         matlab.ui.control.Label
        currentSizeEdit                matlab.ui.control.NumericEditField
        currentfilenameEditFieldLabel  matlab.ui.control.Label
        currentfilenameEditField       matlab.ui.control.EditField
        nextdumpfileButton             matlab.ui.control.Button
        jobqueueTextAreaLabel          matlab.ui.control.Label
        jobqueueTextArea               matlab.ui.control.TextArea
        parallelpreprocCheckBox        matlab.ui.control.CheckBox
        autodumpCheckBox               matlab.ui.control.CheckBox
        autoDumpsizeMBDropDownLabel    matlab.ui.control.Label
        autoDumpsizeDropDown           matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        
        rawDat%
        tmr % reading the tt
        bunchNum=0;
        measurementParams=struct('gateTime',1,'cycleTime',1)
        saveStatus % contains information on the tt_dumpManager last run
        ctr % counter measurement


    end
    
    properties (Access = public)
        autoDumpFlag % automatically dump a file at set size
        currDataFile %
        dump % Description
        tt % time tagger
        currFileNum=1 % Description
        nextDumpFlag %request next dump file
        preProcQueue % queue to process the bin files into MIT compatible files
        parPool % parallel pool processing object
        jobs={}% the list of parallel jobs
    end
    
    methods (Access = private)
        
        function status = saveData(app,obj,event)
            %status contains information regarding current saving status: file name, size etc...
            try
            status=tt_dumpManager(app);
            app.saveStatus=status;
            toggleLamp(app);
            app.lastreadoutEditField.Value=datestr(now,'HH:MM:SS');
            if app.rateMeasCheckBox.Value
                ctrDat=app.ctr.getData();
                app.clickratechan1EditField.Value=mean(ctrDat(1,end-100:end));
                app.clickratechan2EditField.Value=mean(ctrDat(2,end-100:end));
                app.clickratechan3EditField.Value=mean(ctrDat(3,end-100:end));
            end
            
            if app.autoDumpFlag
                    disp(app.autoDumpsizeDropDown.Value);
            end
            
            
%             app.checkAndClearQueue();
            catch err
                fprintf("%s",err.message);
            end
        end
        
        
        function toggleLamp(app)
            if all(app.measuringLamp.Color==[0,1,0])
                app.measuringLamp.Color=[0.94,0.94,0.94];
            else
                app.measuringLamp.Color=[0,1,0];
            end
        end
        
        
        function num = getCurrentFileNum(app)
            num=1;
        end
        
        function folder = getCurrentSaveFolder(app)
            basePath=fullfile(fileparts(which('basicImports')),'..','..','..','Measurements');
            month=datestr(datetime('now'),'mm');
            year=datestr(datetime('now'),'YYYY');
            day=datestr(datetime('now'),'dd');
            folder=fullfile(basePath,year,month,day,'tt');
        end
    end
    
    methods (Access = public)
        
        function toggleMeas(app,state)
            global p
            app.StartMeasurementButton.Value=state;
            if state
                app.StartMeasurementButton.Text='Stop Measurement';
                app.nextDumpFlag=1;
                start(app.tmr);
            else
                app.StartMeasurementButton.Text='Start Measurement';
                app.measuringLamp.Color=[0.94,0.94,0.94];
                if strcmpi(class(app.dump),'TTDump')
                    app.dump.stop;
                    folder=getCurrentSaveFolder(app);
                    [~,this_file_name]=getNextDumpFileName(folder);
                    if exist('p','var') && ~strcmpi(this_file_name,'')
                        parts=split(this_file_name,'.');
                        fn=parts{1};
                        
                        save(fullfile(folder,['p_' fn(4:end) '.mat']),'p');
                        %                         queue=app.preProcQueue;
                        if app.parallelpreprocCheckBox.Value
                        app.jobs{end+1}=parfeval(@binFileToMatQueue, 0,fullfile(folder,this_file_name), app.preProcQueue); 
                        disp(sprintf("sent %s to queue\n",this_file_name));
                        end
                        %                         listener = afterEach(app.preProcQueue, @binToMatDone);
                        %                         for ind=1:length(app.jobs)
                        %                             if strcmpi(app.jobs{ind}.State,'finished')
                        %                                 app.jobs{ind}=[];
                        %                             end
                        %                         end
                        
                        %                         end
                        
                    else
                        warning('no p variable. not saving a .mat file')
                    end
                    
                end
                %                 app.currFileNum=app.currFileNum+1;
                app.nextDumpFlag=1;
                
                
                stop(app.tmr);
                
            end
            
        end
        
        function setMeasurementParams(app,params)
            if isfield(params,'gateTime')
                app.gateTimenSEditField.Value=params.gateTime;
                app.measurementParams.gateTime=params.gateTime;
            end
            if isfield(params,'cycleTime')
                app.cycleTimenSEditField.Value=params.cycleTime;
                app.measurementParams.cycleTime=params.cycleTime;
            end
            
            
        end
        
        function params = getMeasurementParams(app)
            params=app.measurementParams;
        end
        
        function bunchNum = getBunchNum(app)
            bunchNum=app.bunchNum;
            
        end
        
        function newBunchNum= incBunchNum(app)
            app.bunchNum=app.bunchNum+1;
            newBunchNum=app.bunchNum;
        end
        
        function bunchSize = getBunchSize(app)
            bunchSize=app.bunchSize;
        end
        
        function success = nextDump(app)
            app.dump.stop;
            app.nextDumpFlag=1;
            tt_dumpManager(app);
            folder=getCurrentSaveFolder(app);
            [prev_name,this_file_name]=getNextDumpFileName(folder);
            if isempty(this_file_name)
                this_file_name=prev_name;
            end
            
            if exist('p','var') && ~strcmpi(this_file_name,'')
                parts=split(this_file_name,'.');
                fn=parts{1};
                
                save(fullfile(folder,['p_' fn(4:end) '.mat']),'p');
                %                         queue=app.preProcQueue;
                
                
            end
            if app.parallelpreprocCheckBox.Value
            app.jobs{end+1}=parfeval(@binFileToMatQueue, 0,fullfile(folder,this_file_name), app.preProcQueue);
            disp(sprintf("sent %s to queue\n",this_file_name));
            end
            disp('next dump successful');
            success=1;
%             app.checkAndClearQueue()
        end
        
        
        function status = checkAndClearQueue(app)
            
            jctr=0;
            finishedJobs=[];
            unfinishedJobs=[];
            errorJobs=[];
            for ind=1:length(app.jobs)
                try
                    fetchOutputs(app.jobs{ind});
                catch
                    disp('error in job %s',ind);
                end
                thisJob=app.jobs{ind};
                
                if strcmpi(thisJob.State,'finished') && isempty(thisJob.Error)
                    jctr=jctr+1;
                    finishedJobs(end+1)=ind;
                    
                else
                    if ~isempty(app.jobs{ind}.Error)
                        errorJobs(end+1)=ind;
                    end
                    unfinishedJobs(end+1)=ind;
                end
                
                
                %                 if strcmpi(thisJob.State,'')
                %                     disp(ind);
                %                 end
                
            end
            text=sprintf('There are %d jobs in the queue\n',length(app.jobs));
            text=[text sprintf('There are %d finished jobs in the queue\n',jctr)];
            
            app.jobqueueTextArea.Value=sprintf(['Unfinished jobs:%s\n' 'Finished jobs: %s\n' 'error jobs: %s\n'],num2str(unfinishedJobs),num2str(finishedJobs),num2str(errorJobs));
%             app.jobs={app.jobs{unfinishedJobs}};
            status=1;
            
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tt=TimeTagger;
            app.testsignal1CheckBox.Value=app.tt.getTestSignal(1);
            app.testsignal2CheckBox.Value=app.tt.getTestSignal(2);
            app.testsignal3CheckBox.Value=app.tt.getTestSignal(3);
            app.tmr=timer('ExecutionMode','fixedDelay','Period',1,'StartDelay',0);
            app.tmr.TimerFcn=@app.saveData;
            app.autoDumpFlag=0;
            app.currFileNum=app.getCurrentFileNum;
            app.nextDumpFlag=1;
            if app.parallelpreprocCheckBox.Value
            currpool = gcp('nocreate'); % If no pool, do not create new one.
            if isempty(currpool)
                app.parPool=parpool('local',4);
            else
                app.parPool=currpool;
            end
            
            app.preProcQueue = parallel.pool.DataQueue; %setup the background processing queue
            %             app.preProcQueue =parallel.pool.PollableDataQueue; %setup the background processing queue
            end
            
        end

        % Close request function: ttReaderUIFigure
        function ttReaderUIFigureCloseRequest(app, event)
            app.tmr.stop()
            disp('tmr stopped');
            pause(app.readoutperiodsSpinner.Value);
            delete(app.tmr)
            if strcmpi(class(app.dump),'TTDump')
                app.dump.stop
            end
            delete(app)
        end

        % Value changed function: readoutperiodsSpinner
        function readoutperiodsSpinnerValueChanged(app, event)
            value = app.readoutperiodsSpinner.Value;
            stop(app.tmr)
            app.tmr.Period=value;
            start(app.tmr)
        end

        % Button pushed function: resettimetaggerButton
        function resettimetaggerButtonPushed(app, event)
            app.tt.reset()
            app.rawDat=TTTimeTagStream(app.tt,6e6,[1,2,3]);
        end

        % Callback function
        function ClearmeasurementclassButtonPushed(app, event)
            app.rawDat.clear();
        end

        % Value changed function: StartMeasurementButton
        function StartMeasurementButtonValueChanged(app, event)
            value = app.StartMeasurementButton.Value;
            toggleMeas(app,value);
            
            
        end

        % Value changed function: testsignal1CheckBox
        function testsignal1CheckBoxValueChanged(app, event)
            value = app.testsignal1CheckBox.Value;
            app.tt.setTestSignal(1,value==1)
        end

        % Value changed function: testsignal2CheckBox
        function testsignal2CheckBoxValueChanged(app, event)
            value = app.testsignal2CheckBox.Value;
            app.tt.setTestSignal(2,value==1)
        end

        % Value changed function: testsignal3CheckBox
        function testsignal3CheckBoxValueChanged(app, event)
            value = app.testsignal3CheckBox.Value;
            app.tt.setTestSignal(3,value==1)
        end

        % Button pushed function: OpensavelocationButton
        function OpensavelocationButtonPushed(app, event)
            basePath=fullfile(fileparts(which('basicImports')),'..','..','..','Measurements');
            month=datestr(datetime('now'),'mm');
            year=datestr(datetime('now'),'YYYY');
            day=datestr(datetime('now'),'dd');
%             file_base=datestr(datetime('now'),'ddmmYY');
            winopen(fullfile(basePath,year,month,day,'tt'));
        end

        % Callback function
        function BunchsizeEditFieldValueChanged(app, event)
            value = app.BunchsizeEditField.Value;
            app.bunchSize=value;
            app.rawDat=TTTimeTagStream(app.tt,app.bunchSize,[1,2,3]);
        end

        % Callback function
        function forceclosecurrentfileButtonValueChanged(app, event)
            value = app.forceclosecurrentfileButton.Value;
            app.dump.stop;
        end

        % Callback function
        function forceclosecurrentdumpfileButtonPushed(app, event)
            app.dump.stop
        end

        % Value changed function: rateMeasCheckBox
        function rateMeasCheckBoxValueChanged(app, event)
            value = app.rateMeasCheckBox.Value;
            if value
                app.ctr=TTCounter(app.tt,[1,2,3],10e9,1000);
                
                
            else
                app.ctr.stop
            end
            
        end

        % Button pushed function: nextdumpfileButton
        function nextdumpfileButtonPushed(app, event)
            app.nextDump();
            
        end

        % Value changed function: autodumpCheckBox
        function autodumpCheckBoxValueChanged(app, event)
            value = app.autodumpCheckBox.Value;
            app.autoDumpsizeDropDown.Enable=value;
            app.autoDumpFlag=value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ttReaderUIFigure = uifigure;
            app.ttReaderUIFigure.Name = 'ttReader';
            app.ttReaderUIFigure.CloseRequestFcn = createCallbackFcn(app, @ttReaderUIFigureCloseRequest, true);
            app.resettimetaggerButton = uibutton(app.ttReaderUIFigure, 'push');
            app.resettimetaggerButton.ButtonPushedFcn = createCallbackFcn(app, @resettimetaggerButtonPushed, true);
            app.resettimetaggerButton.Text = 'reset time tagger';
            app.measuringLampLabel = uilabel(app.ttReaderUIFigure);
            app.measuringLampLabel.Text = 'measuring...';
            app.measuringLamp = uilamp(app.ttReaderUIFigure);
            app.TimeTaggerreadoutserverLabel = uilabel(app.ttReaderUIFigure);
            app.TimeTaggerreadoutserverLabel.Text = 'TimeTagger readout server';
            app.readoutperiodsSpinnerLabel = uilabel(app.ttReaderUIFigure);
            app.readoutperiodsSpinnerLabel.Text = 'readout period [s]';
            app.readoutperiodsSpinner = uispinner(app.ttReaderUIFigure);
            app.readoutperiodsSpinner.ValueChangedFcn = createCallbackFcn(app, @readoutperiodsSpinnerValueChanged, true);
            app.readoutperiodsSpinner.Value = 1;
            app.lastreadoutEditFieldLabel = uilabel(app.ttReaderUIFigure);
            app.lastreadoutEditFieldLabel.Text = 'last readout';
            app.lastreadoutEditField = uieditfield(app.ttReaderUIFigure, 'text');
            app.lastreadoutEditField.Editable = 'off';
            app.StartMeasurementButton = uibutton(app.ttReaderUIFigure, 'state');
            app.StartMeasurementButton.ValueChangedFcn = createCallbackFcn(app, @StartMeasurementButtonValueChanged, true);
            app.StartMeasurementButton.Text = 'Start Measurement';
            app.gateTimenSEditFieldLabel = uilabel(app.ttReaderUIFigure);
            app.gateTimenSEditFieldLabel.Enable = 'off';
            app.gateTimenSEditFieldLabel.Text = 'gate Time [nS]';
            app.gateTimenSEditField = uieditfield(app.ttReaderUIFigure, 'numeric');
            app.gateTimenSEditField.Editable = 'off';
            app.gateTimenSEditField.Enable = 'off';
            app.cycleTimenSEditFieldLabel = uilabel(app.ttReaderUIFigure);
            app.cycleTimenSEditFieldLabel.Enable = 'off';
            app.cycleTimenSEditFieldLabel.Text = 'cycle Time [nS]';
            app.cycleTimenSEditField = uieditfield(app.ttReaderUIFigure, 'numeric');
            app.cycleTimenSEditField.Editable = 'off';
            app.cycleTimenSEditField.Enable = 'off';
            app.cycleNumberEditFieldLabel = uilabel(app.ttReaderUIFigure);
            app.cycleNumberEditFieldLabel.Enable = 'off';
            app.cycleNumberEditFieldLabel.Text = 'cycle Number';
            app.cycleNumberEditField = uieditfield(app.ttReaderUIFigure, 'numeric');
            app.cycleNumberEditField.Editable = 'off';
            app.cycleNumberEditField.Enable = 'off';
            app.bunchNumberEditFieldLabel = uilabel(app.ttReaderUIFigure);
            app.bunchNumberEditFieldLabel.Enable = 'off';
            app.bunchNumberEditFieldLabel.Text = 'bunch Number';
            app.bunchNumberEditField = uieditfield(app.ttReaderUIFigure, 'numeric');
            app.bunchNumberEditField.Editable = 'off';
            app.bunchNumberEditField.Enable = 'off';
            app.Clickrate10mSPanel = uipanel(app.ttReaderUIFigure);
            app.Clickrate10mSPanel.Title = 'Click rate / 10mS';
            app.clickratechan3EditFieldLabel = uilabel(app.Clickrate10mSPanel);
            app.clickratechan3EditFieldLabel.Text = 'click rate chan 3';
            app.clickratechan3EditField = uieditfield(app.Clickrate10mSPanel, 'numeric');
            app.clickratechan3EditField.Editable = 'off';
            app.clickratechan2EditFieldLabel = uilabel(app.Clickrate10mSPanel);
            app.clickratechan2EditFieldLabel.Text = 'click rate chan 2';
            app.clickratechan2EditField = uieditfield(app.Clickrate10mSPanel, 'numeric');
            app.clickratechan2EditField.Editable = 'off';
            app.clickratechan1EditFieldLabel = uilabel(app.Clickrate10mSPanel);
            app.clickratechan1EditFieldLabel.Text = 'click rate chan 1';
            app.clickratechan1EditField = uieditfield(app.Clickrate10mSPanel, 'numeric');
            app.clickratechan1EditField.Editable = 'off';
            app.rateMeasCheckBox = uicheckbox(app.Clickrate10mSPanel);
            app.rateMeasCheckBox.ValueChangedFcn = createCallbackFcn(app, @rateMeasCheckBoxValueChanged, true);
            app.rateMeasCheckBox.Text = 'Enable click rate measurement';
            app.TestsignalsPanel = uipanel(app.ttReaderUIFigure);
            app.TestsignalsPanel.Title = 'Test signals';
            app.testsignal1CheckBox = uicheckbox(app.TestsignalsPanel);
            app.testsignal1CheckBox.ValueChangedFcn = createCallbackFcn(app, @testsignal1CheckBoxValueChanged, true);
            app.testsignal1CheckBox.Text = 'test signal 1';
            app.testsignal2CheckBox = uicheckbox(app.TestsignalsPanel);
            app.testsignal2CheckBox.ValueChangedFcn = createCallbackFcn(app, @testsignal2CheckBoxValueChanged, true);
            app.testsignal2CheckBox.Text = 'test signal 2';
            app.testsignal3CheckBox = uicheckbox(app.TestsignalsPanel);
            app.testsignal3CheckBox.ValueChangedFcn = createCallbackFcn(app, @testsignal3CheckBoxValueChanged, true);
            app.testsignal3CheckBox.Text = 'test signal 3';
            app.FileinfoPanel = uipanel(app.ttReaderUIFigure);
            app.FileinfoPanel.Title = 'File info';
            app.OpensavelocationButton = uibutton(app.FileinfoPanel, 'push');
            app.OpensavelocationButton.ButtonPushedFcn = createCallbackFcn(app, @OpensavelocationButtonPushed, true);
            app.OpensavelocationButton.Text = 'Open save location...';
            app.currentfilesizeMBLabel = uilabel(app.FileinfoPanel);
            app.currentfilesizeMBLabel.Text = 'current file size [MB]';
            app.currentSizeEdit = uieditfield(app.FileinfoPanel, 'numeric');
            app.currentSizeEdit.ValueDisplayFormat = '%.2f';
            app.currentSizeEdit.Editable = 'off';
            app.currentfilenameEditFieldLabel = uilabel(app.FileinfoPanel);
            app.currentfilenameEditFieldLabel.Text = 'current file name';
            app.currentfilenameEditField = uieditfield(app.FileinfoPanel, 'text');
            app.currentfilenameEditField.Editable = 'off';
            app.nextdumpfileButton = uibutton(app.ttReaderUIFigure, 'push');
            app.nextdumpfileButton.ButtonPushedFcn = createCallbackFcn(app, @nextdumpfileButtonPushed, true);
            app.nextdumpfileButton.Text = 'next dump file';
            app.jobqueueTextAreaLabel = uilabel(app.ttReaderUIFigure);
            app.jobqueueTextAreaLabel.Text = 'job queue';
            app.jobqueueTextArea = uitextarea(app.ttReaderUIFigure);
            app.parallelpreprocCheckBox = uicheckbox(app.ttReaderUIFigure);
            app.parallelpreprocCheckBox.Enable = 'off';
            app.parallelpreprocCheckBox.Text = 'parallel pre proc';
            app.autodumpCheckBox = uicheckbox(app.ttReaderUIFigure);
            app.autodumpCheckBox.ValueChangedFcn = createCallbackFcn(app, @autodumpCheckBoxValueChanged, true);
            app.autodumpCheckBox.Text = 'auto dump';
            app.autoDumpsizeMBDropDownLabel = uilabel(app.ttReaderUIFigure);
            app.autoDumpsizeMBDropDownLabel.Enable = 'off';
            app.autoDumpsizeMBDropDownLabel.Text = 'autoDump size [MB]';
            app.autoDumpsizeDropDown = uidropdown(app.ttReaderUIFigure);
            app.autoDumpsizeDropDown.Items = {'10', '20', '30', '40', '50', '60', '70', '80', '90', '100', '110', '120', '130', '140', '150', '200', '250', '300', '350', '400', '450', '500', ''};
            app.autoDumpsizeDropDown.Enable = 'off';
            app.autoDumpsizeDropDown.Value = '50';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ttReader

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ttReaderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ttReaderUIFigure)
        end
    end
end