classdef ttServer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end

    
    properties (Access = private)
        dump = []; % this is the ttDump object
        fileNames = {};
    end
    
    methods (Access = private)
        
        function results = nextDump(app)
            if isempty(app.dump)
               error('tring to run nextDump before firstDump ran'); 
            end
            app.dump.stop;
            app.fileNames{end+1}=fullfile(getCurrentSaveFolder,getNextDumpFileName(getCurrentSaveFolder));
            app.dump = TTDump(inst.tt,r.fileNames{end},1e9,[1,2,3]);
            %TODO: call ttProsesor to prosess the previus file
        end
        
        function firstDump(app)
            app.fileNames{end+1} = fullfile(getCurrentSaveFolder,getNextDumpFileName(getCurrentSaveFolder));
            app.dump = TTDump(inst.tt,app.fileNames{1},1e9,[1,2,3]);
            pause(0.1);
        end
    end
    

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ttServer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end