classdef ttRateApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        meanlast100photonsperusEditFieldLabel  matlab.ui.control.Label
        meanlast100_1EditField          matlab.ui.control.NumericEditField
        UIAxes_2                        matlab.ui.control.UIAxes
        meanlast100photonsperuSEditFieldLabel  matlab.ui.control.Label
        meanlast100_2EditField          matlab.ui.control.NumericEditField
        StartButton                     matlab.ui.control.StateButton
        photonrateKHzLabel              matlab.ui.control.Label
        rate_2EditField                 matlab.ui.control.NumericEditField
        photonrateKHzLabel_2            matlab.ui.control.Label
        rate_1EditField                 matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        UIAxes_ch1and2                  matlab.ui.control.UIAxes
        meanlast100photonsperusEditFieldLabel_2  matlab.ui.control.Label
        meanlast100__ch1and2_EditField  matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        tt % Description
        ctr % Description
        tmr % Description
        photonRateVec% Description
    end

    methods (Access = private)
    
        function getData(app, obj, event)
           app.photonRateVec=app.ctr.getData();
           plot(app.UIAxes,app.photonRateVec(1,:));
           plot(app.UIAxes_2,app.photonRateVec(2,:));
           plot(app.UIAxes_ch1and2,sum(app.photonRateVec(:,:),1));
           app.meanlast100_1EditField.Value=(mean(app.photonRateVec(1,end-100:end))*1e-4);
           app.meanlast100_2EditField.Value=(mean(app.photonRateVec(2,end-100:end))*1e-4);
           app.rate_1EditField.Value=1e3*(mean(app.photonRateVec(1,end-100:end))*1e-4);
           app.rate_2EditField.Value=1e3*(mean(app.photonRateVec(2,end-100:end))*1e-4);
           app.meanlast100__ch1and2_EditField.Value=(mean(sum(app.photonRateVec(:,end-100:end),1))*1e-4);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tt=TimeTagger();
            app.ctr=TTCounter(app.tt,[2,3],10e9,1000);
            app.tmr=timer('ExecutionMode','fixedRate','Period',0.1);
            app.tmr.TimerFcn=@app.getData;
            start(app.tmr)
        end

        % Callback function
        function stopButtonPushed(app, event)
            stop(app.tmr)
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            if value
                start(app.tmr);
                app.StartButton.Text="stop";
            else
                stop(app.tmr);
                app.StartButton.Text="start";
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ch1 count rate / 10mS')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.meanlast100photonsperusEditFieldLabel = uilabel(app.UIFigure);
            app.meanlast100photonsperusEditFieldLabel.Text = 'mean last 100 [photons per us] ';
            app.meanlast100_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.meanlast100_1EditField.ValueDisplayFormat = '%.2e';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'ch2 count rate / 10mS')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.meanlast100photonsperuSEditFieldLabel = uilabel(app.UIFigure);
            app.meanlast100photonsperuSEditFieldLabel.Text = 'mean last 100  [photons per uS]';
            app.meanlast100_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.meanlast100_2EditField.ValueDisplayFormat = '%.2e';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.StartButton.Value = true;
            app.photonrateKHzLabel = uilabel(app.UIFigure);
            app.photonrateKHzLabel.Text = 'photon rate [KHz]';
            app.rate_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.photonrateKHzLabel_2 = uilabel(app.UIFigure);
            app.photonrateKHzLabel_2.Text = 'photon rate [KHz]';
            app.rate_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'WARNING: AFTER PULSING IS NOT TAKEN INTO ACCOUNT. ACTUAL RATE IS ~ 0.5 OF DISPLAYED RATE';
            app.UIAxes_ch1and2 = uiaxes(app.UIFigure);
            title(app.UIAxes_ch1and2, 'ch1+ch2 count rate / 10mS')
            xlabel(app.UIAxes_ch1and2, 'X')
            ylabel(app.UIAxes_ch1and2, 'Y')
            app.meanlast100photonsperusEditFieldLabel_2 = uilabel(app.UIFigure);
            app.meanlast100photonsperusEditFieldLabel_2.Text = 'mean last 100 [photons per us] ';
            app.meanlast100__ch1and2_EditField = uieditfield(app.UIFigure, 'numeric');
            app.meanlast100__ch1and2_EditField.ValueDisplayFormat = '%.2e';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ttRateApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end