classdef NewExpScriptV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        expScript_wizard                matlab.ui.Figure
        InstrumenttogglePanel           matlab.ui.container.Panel
        scoperesultsCheckBox            matlab.ui.control.CheckBox
        TTresultsCheckBox               matlab.ui.control.CheckBox
        TTdumpmeasurmentCheckBox        matlab.ui.control.CheckBox
        spectrumanalyzerresultsCheckBox  matlab.ui.control.CheckBox
        pictureresultsCheckBox          matlab.ui.control.CheckBox
        pixelflyCheckBox                matlab.ui.control.CheckBox
        handheldCheckBox                matlab.ui.control.CheckBox
        benchtopCheckBox                matlab.ui.control.CheckBox
        IDSCheckBox                     matlab.ui.control.CheckBox
        monitorCheckBox                 matlab.ui.control.CheckBox
        MWsourceCheckBox                matlab.ui.control.CheckBox
        functiongeneratorCheckBox       matlab.ui.control.CheckBox
        scriptdetalesPanel              matlab.ui.container.Panel
        expNameEditFieldLabel           matlab.ui.control.Label
        expNameEditField                matlab.ui.control.EditField
        ExperimentFamilyDropDownLabel   matlab.ui.control.Label
        ExperimentFamilyDropDown        matlab.ui.control.DropDown
        ExperimentdescriptionEditFieldLabel  matlab.ui.control.Label
        ExperimentdescriptionEditField  matlab.ui.control.EditField
        scansetupPanel                  matlab.ui.container.Panel
        innerloopvarEditFieldLabel      matlab.ui.control.Label
        innerloopvarEditField           matlab.ui.control.EditField
        innerloopvalsEditFieldLabel     matlab.ui.control.Label
        innerloopvalsEditField          matlab.ui.control.EditField
        outerloopvarEditFieldLabel      matlab.ui.control.Label
        outerloopvarEditField           matlab.ui.control.EditField
        outerloopvalsEditFieldLabel     matlab.ui.control.Label
        outerloopvalsEditField          matlab.ui.control.EditField
        innerloopnumpNInnerEditFieldLabel  matlab.ui.control.Label
        innerloopnumpNInnerEditField    matlab.ui.control.NumericEditField
        averagenumpNAverageEditFieldLabel  matlab.ui.control.Label
        averagenumpNAverageEditField    matlab.ui.control.NumericEditField
        outerloopnumpNOuterEditFieldLabel  matlab.ui.control.Label
        outerloopnumpNOuterEditField    matlab.ui.control.NumericEditField
        sequencesetupPanel              matlab.ui.container.Panel
        actiontypeDropDownLabel         matlab.ui.control.Label
        actiontypeDropDown              matlab.ui.control.DropDown
        addBlockUITable                 matlab.ui.control.Table
        addBlockButton                  matlab.ui.control.Button
        FinishButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        folderList = strings(0);% a list of expScript folders, for experiment family
        p; %plist
        actionList %a struct with three fields each containing a list of the actions in Block. async, atomic, and compound
        b = Block; %a Block object
        s = sqncr; %a sqncr object
        addBlockColumnList;
        
    end
    
    methods (Access = private)
        
        function updateFolderList(app)
            appFolder = mfilename('fullpath');
            appFolder(find(appFolder=='\',1,'last'):end) = [];
            MyFolderInfo = dir([appFolder '\..\ExpScripts']);
            for ii = 1:length(MyFolderInfo)
                if MyFolderInfo(ii).isdir && ~strcmpi(MyFolderInfo(ii).name,'.') && ~strcmpi(MyFolderInfo(ii).name,'..')
                    app.folderList(end+1) = MyFolderInfo(ii).name;
                end
            end
          app.ExperimentFamilyDropDown.Items = [app.ExperimentFamilyDropDown.Items(1:2),app.folderList];
        end
        
        function updateIntrumentToggle(app)
            app.scoperesultsCheckBox.Value = app.p.hasScopResults;
            app.TTresultsCheckBox.Value = app.p.hasTTresults;
            app.TTdumpmeasurmentCheckBox.Value = app.p.ttDumpMeasurement;
            app.spectrumanalyzerresultsCheckBox.Value = app.p.hasSpecResults;
            app.pictureresultsCheckBox.Value = app.p.hasPicturesResults;
            app.pixelflyCheckBox.Value = ~app.p.pfLiveMode;
            app.IDSCheckBox.Value = ~app.p.idsLiveMode;
            if isfield(app.p,'idsMonitor')
            app.monitorCheckBox.Value = app.p.idsMonitor;
            else
            app.monitorCheckBox.Value = 0;
            end
            app.handheldCheckBox.Value = app.p.handheldSpecRes;
            app.benchtopCheckBox.Value = app.p.benchtopSpecRes;
            app.MWsourceCheckBox.Value = app.p.mwSource;
            app.functiongeneratorCheckBox.Value = app.p.FunctionGen;
            
        end
        
        
        function updateAddBlockList(app)
            switch app.actiontypeDropDown.Value
                case 'atomic'
                    app.addBlockUITable.Data = table(categorical(app.actionList.atomic'));
                    app.addBlockUITable.Data(2:end,:) = []; %this line is to make the table a single line
                    app.addBlockUITable.ColumnEditable = true;
                    
                case 'async'
                    app.addBlockUITable.Data = table(categorical(app.actionList.async'));
                    app.addBlockUITable.Data(2:end,:) = []; %this line is to make the table a single line
                    app.addBlockUITable.ColumnEditable = true;
                case 'compound'
                    app.addBlockUITable.Data = table(categorical(app.actionList.compound'));
                    app.addBlockUITable.Data(2:end,:) = []; %this line is to make the table a single line
                    app.addBlockUITable.ColumnEditable = true;
            end
        end
        
        function updateAddBlockColumn(app,actionName)
            for ii = 1:length(app.addBlockColumnList)
               if app.addBlockColumnList{ii}{1}==actionName
%                    app.addBlockUITable.ColumnName = {'actionName',app.addBlockColumnList{ii}{2:end}};     
                       for jj = 2:length(app.addBlockColumnList{ii})
                           if ~isvarname(app.addBlockColumnList{ii}{jj}) %check if the name is not good as varname.
                              %find and replace space with _
                               app.addBlockColumnList{ii}{jj} = regexprep(app.addBlockColumnList{ii}{jj}, ' ', '_');
                           end
                           app.addBlockUITable.Data.(app.addBlockColumnList{ii}{jj}) = strings(size(app.addBlockUITable.Data,1),1);
                       end
               end
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global p;
            initp;
            app.p = p;
            app.updateIntrumentToggle;
            app.updateFolderList;
%             app.addBlockColumnList = [app.b.atomicActionInputMap,app.b.asyncActionInputMap];
app.addBlockColumnList =  app.b.atomicActionInputMap;
%update action list
            allAtomic = app.b.atomic; %atomic action also contain the async actions;
            [~,diifInd] = setdiff(allAtomic,app.b.async); %find the difference indises between allAtominc and async, thay are the atomic actions
            app.actionList.atomic = allAtomic(diifInd);
            app.actionList.async = app.b.async;
            app.actionList.compound = app.b.compound;
            app.updateAddBlockList
%             disp(app.folderList);
        end

        % Value changed function: ExperimentFamilyDropDown
        function ExperimentFamilyDropDownValueChanged(app, event)
            value = app.ExperimentFamilyDropDown.Value;
            if strcmpi(value,'add family')
               answer = inputdlg('new family name:');
               app.ExperimentFamilyDropDown.Items = [app.ExperimentFamilyDropDown.Items,answer{1}];
            end
        end

        % Value changed function: scoperesultsCheckBox
        function scoperesultsCheckBoxValueChanged(app, event)
            value = app.scoperesultsCheckBox.Value;
            app.p.hasScopResults = value;
        end

        % Value changed function: TTresultsCheckBox
        function TTresultsCheckBoxValueChanged(app, event)
            value = app.TTresultsCheckBox.Value;
            app.p.hasTTresults = value;
        end

        % Value changed function: TTdumpmeasurmentCheckBox
        function TTdumpmeasurmentCheckBoxValueChanged(app, event)
            value = app.TTdumpmeasurmentCheckBox.Value;
            app.p.ttDumpMeasurement = value;
        end

        % Value changed function: spectrumanalyzerresultsCheckBox
        function spectrumanalyzerresultsCheckBoxValueChanged(app, event)
            value = app.spectrumanalyzerresultsCheckBox.Value;
            app.p.spectrumAnaParams = value;
            app.handheldCheckBox.Enable = value;
            app.benchtopCheckBox.Enable = value;
        end

        % Value changed function: pictureresultsCheckBox
        function pictureresultsCheckBoxValueChanged(app, event)
            value = app.pictureresultsCheckBox.Value;
            app.p.hasPicturesResults = value;
            app.pixelflyCheckBox.Enable = value;
            app.IDSCheckBox.Enable = value;
            if value ==0
              app.monitorCheckBox.Enable = 0;           
            end
        end

        % Value changed function: handheldCheckBox
        function handheldCheckBoxValueChanged(app, event)
            value = app.handheldCheckBox.Value;
            app.p.handheldSpecRes = value;
        end

        % Value changed function: benchtopCheckBox
        function benchtopCheckBoxValueChanged(app, event)
            value = app.benchtopCheckBox.Value;
            app.p.benchtopSpecRes = value;
        end

        % Value changed function: pixelflyCheckBox
        function pixelflyCheckBoxValueChanged(app, event)
            value = app.pixelflyCheckBox.Value;
            app.p.pfLiveMode = ~value;
            
        end

        % Value changed function: IDSCheckBox
        function IDSCheckBoxValueChanged(app, event)
            value = app.IDSCheckBox.Value;
            app.p.idsLiveMode = ~value;
            app.monitorCheckBox.Enable = value;
        end

        % Value changed function: MWsourceCheckBox
        function MWsourceCheckBoxValueChanged(app, event)
            value = app.MWsourceCheckBox.Value;
            app.p.mwSource = value;
        end

        % Value changed function: functiongeneratorCheckBox
        function functiongeneratorCheckBoxValueChanged(app, event)
            value = app.functiongeneratorCheckBox.Value;
            app.p.FunctionGen = value;
        end

        % Value changed function: expNameEditField
        function expNameEditFieldValueChanged(app, event)
            value = app.expNameEditField.Value;
            app.p.expName = value;
        end

        % Value changed function: innerloopnumpNInnerEditField
        function innerloopnumpNInnerEditFieldValueChanged(app, event)
            value = app.innerloopnumpNInnerEditField.Value;
            app.p.NInner = value;
        end

        % Value changed function: outerloopnumpNOuterEditField
        function outerloopnumpNOuterEditFieldValueChanged(app, event)
            value = app.outerloopnumpNOuterEditField.Value;
            app.p.NOuter = value;
        end

        % Value changed function: averagenumpNAverageEditField
        function averagenumpNAverageEditFieldValueChanged(app, event)
            value = app.averagenumpNAverageEditField.Value;
            app.p.NAverage = value;
        end

        % Value changed function: innerloopvarEditField
        function innerloopvarEditFieldValueChanged(app, event)
            value = app.innerloopvarEditField.Value;
            app.p.loopVars{1} = value;
            app.p.(value) = app.p.INNERLOOPVAR;
        end

        % Value changed function: innerloopvalsEditField
        function innerloopvalsEditFieldValueChanged(app, event)
            value = app.innerloopvalsEditField.Value;
            app.p.loopVals{1} = eval(value);
        end

        % Value changed function: outerloopvarEditField
        function outerloopvarEditFieldValueChanged(app, event)
            value = app.outerloopvarEditField.Value;
            app.p.loopVars{2} = value;
            app.p.(value) = app.p.INNERLOOPVAR;
        end

        % Value changed function: outerloopvalsEditField
        function outerloopvalsEditFieldValueChanged(app, event)
            value = app.outerloopvalsEditField.Value;
            app.p.loopVals{2} = eval(value);
        end

        % Value changed function: actiontypeDropDown
        function actiontypeDropDownValueChanged(app, event)
            value = app.actiontypeDropDown.Value;
            app.updateAddBlockList;
        end

        % Cell edit callback: addBlockUITable
        function addBlockUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.updateAddBlockColumn(newData);
        end

        % Button pushed function: addBlockButton
        function addBlockButtonPushed(app, event)
            %TODO : make this button clibk add the existing block to the file
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            %TODO : maker this botton savethe file and write all the lines
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.expScript_wizard = uifigure;
            app.expScript_wizard.Name = 'expScript wizard';
            app.InstrumenttogglePanel = uipanel(app.expScript_wizard);
            app.InstrumenttogglePanel.Title = 'Instrument toggle';
            app.scoperesultsCheckBox = uicheckbox(app.InstrumenttogglePanel);
            app.scoperesultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @scoperesultsCheckBoxValueChanged, true);
            app.scoperesultsCheckBox.Text = 'scope results';
            app.TTresultsCheckBox = uicheckbox(app.InstrumenttogglePanel);
            app.TTresultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @TTresultsCheckBoxValueChanged, true);
            app.TTresultsCheckBox.Text = 'TT results';
            app.TTdumpmeasurmentCheckBox = uicheckbox(app.InstrumenttogglePanel);
            app.TTdumpmeasurmentCheckBox.ValueChangedFcn = createCallbackFcn(app, @TTdumpmeasurmentCheckBoxValueChanged, true);
            app.TTdumpmeasurmentCheckBox.Text = 'TT dump measurment';
            app.spectrumanalyzerresultsCheckBox = uicheckbox(app.InstrumenttogglePanel);
            app.spectrumanalyzerresultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @spectrumanalyzerresultsCheckBoxValueChanged, true);
            app.spectrumanalyzerresultsCheckBox.Text = 'spectrum analyzer results';
            app.pictureresultsCheckBox = uicheckbox(app.InstrumenttogglePanel);
            app.pictureresultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @pictureresultsCheckBoxValueChanged, true);
            app.pictureresultsCheckBox.Text = 'picture results';
            app.pixelflyCheckBox = uicheckbox(app.InstrumenttogglePanel);
            app.pixelflyCheckBox.ValueChangedFcn = createCallbackFcn(app, @pixelflyCheckBoxValueChanged, true);
            app.pixelflyCheckBox.Enable = 'off';
            app.pixelflyCheckBox.Text = 'pixelfly';
            app.handheldCheckBox = uicheckbox(app.InstrumenttogglePanel);
            app.handheldCheckBox.ValueChangedFcn = createCallbackFcn(app, @handheldCheckBoxValueChanged, true);
            app.handheldCheckBox.Enable = 'off';
            app.handheldCheckBox.Text = 'handheld';
            app.benchtopCheckBox = uicheckbox(app.InstrumenttogglePanel);
            app.benchtopCheckBox.ValueChangedFcn = createCallbackFcn(app, @benchtopCheckBoxValueChanged, true);
            app.benchtopCheckBox.Enable = 'off';
            app.benchtopCheckBox.Text = 'benchtop';
            app.IDSCheckBox = uicheckbox(app.InstrumenttogglePanel);
            app.IDSCheckBox.ValueChangedFcn = createCallbackFcn(app, @IDSCheckBoxValueChanged, true);
            app.IDSCheckBox.Enable = 'off';
            app.IDSCheckBox.Text = 'IDS';
            app.monitorCheckBox = uicheckbox(app.InstrumenttogglePanel);
            app.monitorCheckBox.Enable = 'off';
            app.monitorCheckBox.Text = 'monitor';
            app.MWsourceCheckBox = uicheckbox(app.InstrumenttogglePanel);
            app.MWsourceCheckBox.ValueChangedFcn = createCallbackFcn(app, @MWsourceCheckBoxValueChanged, true);
            app.MWsourceCheckBox.Text = 'MW source';
            app.functiongeneratorCheckBox = uicheckbox(app.InstrumenttogglePanel);
            app.functiongeneratorCheckBox.ValueChangedFcn = createCallbackFcn(app, @functiongeneratorCheckBoxValueChanged, true);
            app.functiongeneratorCheckBox.Text = 'function generator';
            app.scriptdetalesPanel = uipanel(app.expScript_wizard);
            app.scriptdetalesPanel.Title = 'script detales';
            app.expNameEditFieldLabel = uilabel(app.scriptdetalesPanel);
            app.expNameEditFieldLabel.Text = 'expName';
            app.expNameEditField = uieditfield(app.scriptdetalesPanel, 'text');
            app.expNameEditField.ValueChangedFcn = createCallbackFcn(app, @expNameEditFieldValueChanged, true);
            app.ExperimentFamilyDropDownLabel = uilabel(app.scriptdetalesPanel);
            app.ExperimentFamilyDropDownLabel.Text = 'Experiment Family';
            app.ExperimentFamilyDropDown = uidropdown(app.scriptdetalesPanel);
            app.ExperimentFamilyDropDown.Items = {'none', 'add family'};
            app.ExperimentFamilyDropDown.ValueChangedFcn = createCallbackFcn(app, @ExperimentFamilyDropDownValueChanged, true);
            app.ExperimentFamilyDropDown.Value = 'none';
            app.ExperimentdescriptionEditFieldLabel = uilabel(app.scriptdetalesPanel);
            app.ExperimentdescriptionEditFieldLabel.Text = 'Experiment description';
            app.ExperimentdescriptionEditField = uieditfield(app.scriptdetalesPanel, 'text');
            app.scansetupPanel = uipanel(app.expScript_wizard);
            app.scansetupPanel.Title = 'scan setup';
            app.innerloopvarEditFieldLabel = uilabel(app.scansetupPanel);
            app.innerloopvarEditFieldLabel.Text = 'inner loop var';
            app.innerloopvarEditField = uieditfield(app.scansetupPanel, 'text');
            app.innerloopvarEditField.ValueChangedFcn = createCallbackFcn(app, @innerloopvarEditFieldValueChanged, true);
            app.innerloopvalsEditFieldLabel = uilabel(app.scansetupPanel);
            app.innerloopvalsEditFieldLabel.Text = 'inner loop vals';
            app.innerloopvalsEditField = uieditfield(app.scansetupPanel, 'text');
            app.innerloopvalsEditField.ValueChangedFcn = createCallbackFcn(app, @innerloopvalsEditFieldValueChanged, true);
            app.innerloopvalsEditField.Tooltip = {'linspace(1'; '100'; 'p.NInner)'};
            app.innerloopvalsEditField.Value = 'linspace(1,100,p.NInner)';
            app.outerloopvarEditFieldLabel = uilabel(app.scansetupPanel);
            app.outerloopvarEditFieldLabel.Text = 'outer loop var';
            app.outerloopvarEditField = uieditfield(app.scansetupPanel, 'text');
            app.outerloopvarEditField.ValueChangedFcn = createCallbackFcn(app, @outerloopvarEditFieldValueChanged, true);
            app.outerloopvalsEditFieldLabel = uilabel(app.scansetupPanel);
            app.outerloopvalsEditFieldLabel.Text = 'outer loop vals';
            app.outerloopvalsEditField = uieditfield(app.scansetupPanel, 'text');
            app.outerloopvalsEditField.ValueChangedFcn = createCallbackFcn(app, @outerloopvalsEditFieldValueChanged, true);
            app.innerloopnumpNInnerEditFieldLabel = uilabel(app.scansetupPanel);
            app.innerloopnumpNInnerEditFieldLabel.Text = 'inner loop num (p.NInner)';
            app.innerloopnumpNInnerEditField = uieditfield(app.scansetupPanel, 'numeric');
            app.innerloopnumpNInnerEditField.ValueChangedFcn = createCallbackFcn(app, @innerloopnumpNInnerEditFieldValueChanged, true);
            app.averagenumpNAverageEditFieldLabel = uilabel(app.scansetupPanel);
            app.averagenumpNAverageEditFieldLabel.Text = 'average num (p.NAverage)';
            app.averagenumpNAverageEditField = uieditfield(app.scansetupPanel, 'numeric');
            app.averagenumpNAverageEditField.ValueChangedFcn = createCallbackFcn(app, @averagenumpNAverageEditFieldValueChanged, true);
            app.outerloopnumpNOuterEditFieldLabel = uilabel(app.scansetupPanel);
            app.outerloopnumpNOuterEditFieldLabel.Text = 'outer loop num (p.NOuter)';
            app.outerloopnumpNOuterEditField = uieditfield(app.scansetupPanel, 'numeric');
            app.outerloopnumpNOuterEditField.ValueChangedFcn = createCallbackFcn(app, @outerloopnumpNOuterEditFieldValueChanged, true);
            app.sequencesetupPanel = uipanel(app.expScript_wizard);
            app.sequencesetupPanel.Title = 'sequence setup';
            app.actiontypeDropDownLabel = uilabel(app.sequencesetupPanel);
            app.actiontypeDropDownLabel.Text = 'action type';
            app.actiontypeDropDown = uidropdown(app.sequencesetupPanel);
            app.actiontypeDropDown.Items = {'async', 'compound', 'atomic'};
            app.actiontypeDropDown.ValueChangedFcn = createCallbackFcn(app, @actiontypeDropDownValueChanged, true);
            app.actiontypeDropDown.Value = 'async';
            app.addBlockUITable = uitable(app.sequencesetupPanel);
            app.addBlockUITable.ColumnName = {'action name'};
            app.addBlockUITable.RowName = {};
            app.addBlockUITable.ColumnEditable = true;
            app.addBlockUITable.CellEditCallback = createCallbackFcn(app, @addBlockUITableCellEdit, true);
            app.addBlockButton = uibutton(app.sequencesetupPanel, 'push');
            app.addBlockButton.ButtonPushedFcn = createCallbackFcn(app, @addBlockButtonPushed, true);
            app.addBlockButton.Text = 'add Block';
            app.FinishButton = uibutton(app.sequencesetupPanel, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
        end
    end

    methods (Access = public)

        % Construct app
        function app = NewExpScriptV2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.expScript_wizard)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.expScript_wizard)
        end
    end
end